classdef EEGSettingsWin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParametersSettingUIFigure       matlab.ui.Figure
        AutomaticThresholdsPanel        matlab.ui.container.Panel
        DetermineautomaticthresholdsLabel  matlab.ui.control.Label
        UsedforpeakdetectionLabel       matlab.ui.control.Label
        PeaksMindistancebetweensamplesLabel  matlab.ui.control.Label
        PosPeaksMinDistEditField        matlab.ui.control.NumericEditField
        PeaksAmplitudethresholdLabel_2  matlab.ui.control.Label
        PosPeaksAmpEditField            matlab.ui.control.NumericEditField
        PeaksMindistancebetweensamplesLabel_2  matlab.ui.control.Label
        NegPeaksMinDistEditField        matlab.ui.control.NumericEditField
        PeaksAmplitudethresholdLabel    matlab.ui.control.Label
        NegPeaksAmpEditField            matlab.ui.control.NumericEditField
        UsedforBaselineDetectionLabel   matlab.ui.control.Label
        CutoffsforALLpeaksLabel         matlab.ui.control.Label
        MinPctCutoffALLPeaksEditField   matlab.ui.control.NumericEditField
        MaxPctCutoffALLPeaksEditField   matlab.ui.control.NumericEditField
        UsedforNoiseDetectionLabel      matlab.ui.control.Label
        CutoffsforPospeaksLabel         matlab.ui.control.Label
        MinPctCutoffPosPeaksEditField   matlab.ui.control.NumericEditField
        MaxPctCutoffPosPeaksEditField   matlab.ui.control.NumericEditField
        CutoffsforNegpeaksLabel         matlab.ui.control.Label
        MinPctCutoffNegPeaksEditField   matlab.ui.control.NumericEditField
        MaxPctCutoffNegPeaksEditField   matlab.ui.control.NumericEditField
        AutomaticSpikeCutoffLabel       matlab.ui.control.Label
        NumberofSDsfrommeanEditFieldLabel  matlab.ui.control.Label
        NumSDfromMeanEditField          matlab.ui.control.NumericEditField
        SpikeDetectionPanel             matlab.ui.container.Panel
        SettingsusedtodeterminespikesLabel  matlab.ui.control.Label
        MaxwidthofspikesEditFieldLabel  matlab.ui.control.Label
        MaxWidthOfSpikesEditField       matlab.ui.control.NumericEditField
        HeightatwhichtocalculatewidthLabel  matlab.ui.control.Label
        HeightPctEditField              matlab.ui.control.NumericEditField
        SpikesMindistancebetweenLabel   matlab.ui.control.Label
        PosSpikeMinDistEditField        matlab.ui.control.NumericEditField
        SpikesMindistancebetweenLabel_2  matlab.ui.control.Label
        NegSpikeMinDistEditField        matlab.ui.control.NumericEditField
        EventDetectionPanel             matlab.ui.container.Panel
        NumSpikesMustHaveEditField      matlab.ui.control.NumericEditField
        MusthaveLabel                   matlab.ui.control.Label
        numberofSpikesLabel             matlab.ui.control.Label
        SecondsForSpikesEditField       matlab.ui.control.NumericEditField
        InLabel                         matlab.ui.control.Label
        numberofSecondsLabel            matlab.ui.control.Label
        MergePosNegEventsButtonGroup    matlab.ui.container.ButtonGroup
        UnionEventsButton               matlab.ui.control.RadioButton
        IntersectEventsButton           matlab.ui.control.RadioButton
        OnlyTakePosEventsButton         matlab.ui.control.RadioButton
        OnlyTakeNegEventsButton         matlab.ui.control.RadioButton
        ConnectEventsCheckBox           matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        spikesbutotherwisefollowrulesLabel  matlab.ui.control.Label
        GlueeventscloserthansEditFieldLabel  matlab.ui.control.Label
        GlueEventsEditField             matlab.ui.control.NumericEditField
        MinseizurelengthsEditFieldLabel  matlab.ui.control.Label
        MinSeizureLengthEditField       matlab.ui.control.NumericEditField
        NoiseDetectionPanel             matlab.ui.container.Panel
        IgnorenoiseshorterthansEditFieldLabel  matlab.ui.control.Label
        IgnoreNoiseEditField            matlab.ui.control.NumericEditField
        JoinnoiseifcloserthansLabel     matlab.ui.control.Label
        JoinNoiseEditField              matlab.ui.control.NumericEditField
        DealWithNoiseButtonGroup        matlab.ui.container.ButtonGroup
        AllowNoiseButton                matlab.ui.control.RadioButton
        SplitEventsAtNoiseLocationsButton  matlab.ui.control.RadioButton
        IgnoreEventsContainingNoiseButton  matlab.ui.control.RadioButton
        SlidingwindowsizesEditFieldLabel  matlab.ui.control.Label
        SlidingWindowSizeEditField      matlab.ui.control.NumericEditField
        SlidingwindowstepsEditFieldLabel  matlab.ui.control.Label
        SlidingWindowStepEditField      matlab.ui.control.NumericEditField
        RestoreDefaultButton            matlab.ui.control.Button
        SaveExitButton                  matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        appCallingMainApp;       % main app object
        
%         sctDefaultSetting; 
    end
    
    methods (Access = private)
        
        function displayParameters(app, mainapp)
        
            % automatic thresholds
            app.PosPeaksMinDistEditField.Value          = mainapp.sctSetting.AutoThresholds.nPosPeaksMinDist; 
            app.PosPeaksAmpEditField.Value              = mainapp.sctSetting.AutoThresholds.dPosPeaksAmp;
            app.NegPeaksMinDistEditField.Value          = mainapp.sctSetting.AutoThresholds.nNegPeaksMinDist; 
            app.NegPeaksAmpEditField.Value              = mainapp.sctSetting.AutoThresholds.dNegPeaksAmp; 
            
            app.MinPctCutoffALLPeaksEditField.Value    = mainapp.sctSetting.AutoThresholds.dPctCutoffALLPeaks(1);
            app.MaxPctCutoffALLPeaksEditField.Value    = mainapp.sctSetting.AutoThresholds.dPctCutoffALLPeaks(2);
            
            app.MinPctCutoffPosPeaksEditField.Value    = mainapp.sctSetting.AutoThresholds.dPctCutoffPosPeaks(1);
            app.MaxPctCutoffPosPeaksEditField.Value    = mainapp.sctSetting.AutoThresholds.dPctCutoffPosPeaks(2);
            app.MinPctCutoffNegPeaksEditField.Value    = mainapp.sctSetting.AutoThresholds.dPctCutoffNegPeaks(1);
            app.MaxPctCutoffNegPeaksEditField.Value    = mainapp.sctSetting.AutoThresholds.dPctCutoffNegPeaks(2);
            
            app.NumSDfromMeanEditField.Value            = mainapp.sctSetting.AutoThresholds.nNumSDfromMean; 
            
            % spike detection 
            app.MaxWidthOfSpikesEditField.Value         = mainapp.sctSetting.SpikeDetection.dMaxWidthOfSpikes;
            app.HeightPctEditField.Value                = mainapp.sctSetting.SpikeDetection.dHeightPct;
            app.PosSpikeMinDistEditField.Value          = mainapp.sctSetting.SpikeDetection.nPosSpikeMinDist;
            app.NegSpikeMinDistEditField.Value          = mainapp.sctSetting.SpikeDetection.nNegSpikeMinDist;
            
            % event detection
            app.NumSpikesMustHaveEditField.Value        = mainapp.sctSetting.EventDetection.nMinNumSpikesAsEvent;
            app.SecondsForSpikesEditField.Value         = mainapp.sctSetting.EventDetection.dMinRollingTimeWin;
            app.MinSeizureLengthEditField.Value         = mainapp.sctSetting.EventDetection.dMinSeizureLength;
            app.GlueEventsEditField.Value               = mainapp.sctSetting.EventDetection.dGlueEvents;
            app.ConnectEventsCheckBox.Value             = mainapp.sctSetting.EventDetection.bConnectEventsCheck;
            
            switch mainapp.sctSetting.EventDetection.MergePosNegEvents.Value
                case 1;     app.UnionEventsButton.Value         = 1; 
                case 2;     app.IntersectEventsButton.Value     = 1;
                case 3;     app.OnlyTakePosEventsButton.Value   = 1;
                case 4;     app.OnlyTakeNegEventsButton.Value   = 1;
            end % switch mainapp.sctSetting.EventDetection.MergePosNegEvents.Value
            
            % noise detection 
            app.IgnoreNoiseEditField.Value              = mainapp.sctSetting.NoiseDetection.dIgnoreNoise;
            app.JoinNoiseEditField.Value                = mainapp.sctSetting.NoiseDetection.dJoinNoise; 
            app.SlidingWindowSizeEditField.Value        = mainapp.sctSetting.NoiseDetection.dSlidingWindowSize; 
            app.SlidingWindowStepEditField.Value        = mainapp.sctSetting.NoiseDetection.dSlidingWindowStep; 
            
            switch mainapp.sctSetting.NoiseDetection.DealWithNoise.Value
                case 1;     app.AllowNoiseButton.Value                  = 1;
                case 2;     app.SplitEventsAtNoiseLocationsButton.Value = 1;
                case 3;     app.IgnoreEventsContainingNoiseButton.Value = 1; 
            end % switch mainapp.sctSetting.NoiseDetection.DealWithNoise.Value
            
        end % function displayParameters(app, mainapp)
        
        function updateParameters(app, mainapp)
        
            % automatic thresholds
            mainapp.sctSetting.AutoThresholds.nPosPeaksMinDist      = app.PosPeaksMinDistEditField.Value; 
            mainapp.sctSetting.AutoThresholds.dPosPeaksAmp          = app.PosPeaksAmpEditField.Value;
            mainapp.sctSetting.AutoThresholds.nNegPeaksMinDist      = app.NegPeaksMinDistEditField.Value; 
            mainapp.sctSetting.AutoThresholds.dNegPeaksAmp          = app.NegPeaksAmpEditField.Value; 
            
            mainapp.sctSetting.AutoThresholds.dPctCutoffALLPeaks   = [app.MinPctCutoffALLPeaksEditField.Value, app.MaxPctCutoffALLPeaksEditField.Value];
            mainapp.sctSetting.AutoThresholds.dPctCutoffPosPeaks   = [app.MinPctCutoffPosPeaksEditField.Value, app.MaxPctCutoffPosPeaksEditField.Value];
            mainapp.sctSetting.AutoThresholds.dPctCutoffNegPeaks   = [app.MinPctCutoffNegPeaksEditField.Value, app.MaxPctCutoffNegPeaksEditField.Value];
            
            mainapp.sctSetting.AutoThresholds.nNumSDfromMean        = app.NumSDfromMeanEditField.Value; 
            
            % spike detection 
            mainapp.sctSetting.SpikeDetection.dMaxWidthOfSpikes     = app.MaxWidthOfSpikesEditField.Value;
            mainapp.sctSetting.SpikeDetection.dHeightPct            = app.HeightPctEditField.Value;
            mainapp.sctSetting.SpikeDetection.nPosSpikeMinDist      = app.PosSpikeMinDistEditField.Value;
            mainapp.sctSetting.SpikeDetection.nNegSpikeMinDist      = app.NegSpikeMinDistEditField.Value;
            
            % event detection
            mainapp.sctSetting.EventDetection.nMinNumSpikesAsEvent  = app.NumSpikesMustHaveEditField.Value;
            mainapp.sctSetting.EventDetection.dMinRollingTimeWin    = app.SecondsForSpikesEditField.Value;
            mainapp.sctSetting.EventDetection.dMinSeizureLength     = app.MinSeizureLengthEditField.Value;
            mainapp.sctSetting.EventDetection.dGlueEvents           = app.GlueEventsEditField.Value;
            mainapp.sctSetting.EventDetection.bConnectEventsCheck   = app.ConnectEventsCheckBox.Value; 
            
            if app.UnionEventsButton.Value;         mainapp.sctSetting.EventDetection.MergePosNegEvents.Value = 1;  end
            if app.IntersectEventsButton.Value;     mainapp.sctSetting.EventDetection.MergePosNegEvents.Value = 2;  end
            if app.OnlyTakePosEventsButton.Value;   mainapp.sctSetting.EventDetection.MergePosNegEvents.Value = 3;  end
            if app.OnlyTakeNegEventsButton.Value;   mainapp.sctSetting.EventDetection.MergePosNegEvents.Value = 4;  end
            mainapp.sctSetting.EventDetection.MergePosNegEvents.Selection = app.MergePosNegEventsButtonGroup.SelectedObject.Text; 
            
            % noise detection 
            mainapp.sctSetting.NoiseDetection.dIgnoreNoise          = app.IgnoreNoiseEditField.Value;
            mainapp.sctSetting.NoiseDetection.dJoinNoise            = app.JoinNoiseEditField.Value; 
            mainapp.sctSetting.NoiseDetection.dSlidingWindowSize    = app.SlidingWindowSizeEditField.Value; 
            mainapp.sctSetting.NoiseDetection.dSlidingWindowStep    = app.SlidingWindowStepEditField.Value; 
            
            if app.AllowNoiseButton.Value;                  mainapp.sctSetting.NoiseDetection.DealWithNoise.Value = 1; end
            if app.SplitEventsAtNoiseLocationsButton.Value; mainapp.sctSetting.NoiseDetection.DealWithNoise.Value = 2; end
            if app.IgnoreEventsContainingNoiseButton.Value; mainapp.sctSetting.NoiseDetection.DealWithNoise.Value = 3; end
            mainapp.sctSetting.NoiseDetection.DealWithNoise.Selection = app.DealWithNoiseButtonGroup.SelectedObject.Text;
        
        end % function sctNewSetting = updateParameters(app, mainapp)
        
%         function restoreDefaultParameters(app, mainapp)
%             
%         end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, sctSettingParameters)
            app.appCallingMainApp = mainapp; 
            displayParameters(app, mainapp); 

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app); 
        end

        % Button pushed function: RestoreDefaultButton
        function RestoreDefaultButtonPushed(app, event)
            app.appCallingMainApp.sctSetting = defaultSettings(app.appCallingMainApp); 
            displayParameters(app, app.appCallingMainApp); 
            
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            updateParameters(app, app.appCallingMainApp); 
            delete(app); 
%             keyboard; 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParametersSettingUIFigure = uifigure;
            app.ParametersSettingUIFigure.Name = 'Parameters Setting';
            app.AutomaticThresholdsPanel = uipanel(app.ParametersSettingUIFigure);
            app.AutomaticThresholdsPanel.Title = 'Automatic Thresholds';
            app.DetermineautomaticthresholdsLabel = uilabel(app.AutomaticThresholdsPanel);
            app.DetermineautomaticthresholdsLabel.Text = 'Determine automatic thresholds';
            app.UsedforpeakdetectionLabel = uilabel(app.AutomaticThresholdsPanel);
            app.UsedforpeakdetectionLabel.Text = 'Used for peak detection';
            app.PeaksMindistancebetweensamplesLabel = uilabel(app.AutomaticThresholdsPanel);
            app.PeaksMindistancebetweensamplesLabel.Text = '(+) Peaks: Min distance between samples';
            app.PosPeaksMinDistEditField = uieditfield(app.AutomaticThresholdsPanel, 'numeric');
            app.PeaksAmplitudethresholdLabel_2 = uilabel(app.AutomaticThresholdsPanel);
            app.PeaksAmplitudethresholdLabel_2.Text = '(+) Peaks: Amplitude threshold';
            app.PosPeaksAmpEditField = uieditfield(app.AutomaticThresholdsPanel, 'numeric');
            app.PeaksMindistancebetweensamplesLabel_2 = uilabel(app.AutomaticThresholdsPanel);
            app.PeaksMindistancebetweensamplesLabel_2.Text = '(-) Peaks: Min distance between samples';
            app.NegPeaksMinDistEditField = uieditfield(app.AutomaticThresholdsPanel, 'numeric');
            app.PeaksAmplitudethresholdLabel = uilabel(app.AutomaticThresholdsPanel);
            app.PeaksAmplitudethresholdLabel.Text = '(-) Peaks: Amplitude threshold';
            app.NegPeaksAmpEditField = uieditfield(app.AutomaticThresholdsPanel, 'numeric');
            app.UsedforBaselineDetectionLabel = uilabel(app.AutomaticThresholdsPanel);
            app.UsedforBaselineDetectionLabel.Text = 'Used for Baseline Detection';
            app.CutoffsforALLpeaksLabel = uilabel(app.AutomaticThresholdsPanel);
            app.CutoffsforALLpeaksLabel.Text = '% Cutoffs for ALL peaks';
            app.MinPctCutoffALLPeaksEditField = uieditfield(app.AutomaticThresholdsPanel, 'numeric');
            app.MaxPctCutoffALLPeaksEditField = uieditfield(app.AutomaticThresholdsPanel, 'numeric');
            app.UsedforNoiseDetectionLabel = uilabel(app.AutomaticThresholdsPanel);
            app.UsedforNoiseDetectionLabel.Text = 'Used for Noise Detection';
            app.CutoffsforPospeaksLabel = uilabel(app.AutomaticThresholdsPanel);
            app.CutoffsforPospeaksLabel.Text = '% Cutoffs for (+) peaks';
            app.MinPctCutoffPosPeaksEditField = uieditfield(app.AutomaticThresholdsPanel, 'numeric');
            app.MaxPctCutoffPosPeaksEditField = uieditfield(app.AutomaticThresholdsPanel, 'numeric');
            app.CutoffsforNegpeaksLabel = uilabel(app.AutomaticThresholdsPanel);
            app.CutoffsforNegpeaksLabel.Text = '% Cutoffs for (-) peaks';
            app.MinPctCutoffNegPeaksEditField = uieditfield(app.AutomaticThresholdsPanel, 'numeric');
            app.MaxPctCutoffNegPeaksEditField = uieditfield(app.AutomaticThresholdsPanel, 'numeric');
            app.AutomaticSpikeCutoffLabel = uilabel(app.AutomaticThresholdsPanel);
            app.AutomaticSpikeCutoffLabel.Text = 'Automatic Spike Cutoff';
            app.NumberofSDsfrommeanEditFieldLabel = uilabel(app.AutomaticThresholdsPanel);
            app.NumberofSDsfrommeanEditFieldLabel.Text = 'Number of SD''s from mean';
            app.NumSDfromMeanEditField = uieditfield(app.AutomaticThresholdsPanel, 'numeric');
            app.SpikeDetectionPanel = uipanel(app.ParametersSettingUIFigure);
            app.SpikeDetectionPanel.Title = 'Spike Detection';
            app.SettingsusedtodeterminespikesLabel = uilabel(app.SpikeDetectionPanel);
            app.SettingsusedtodeterminespikesLabel.Text = 'Settings used to determine spikes';
            app.MaxwidthofspikesEditFieldLabel = uilabel(app.SpikeDetectionPanel);
            app.MaxwidthofspikesEditFieldLabel.Text = 'Max width of spike(s)';
            app.MaxWidthOfSpikesEditField = uieditfield(app.SpikeDetectionPanel, 'numeric');
            app.HeightatwhichtocalculatewidthLabel = uilabel(app.SpikeDetectionPanel);
            app.HeightatwhichtocalculatewidthLabel.Text = 'Height % at which to calculate width';
            app.HeightPctEditField = uieditfield(app.SpikeDetectionPanel, 'numeric');
            app.SpikesMindistancebetweenLabel = uilabel(app.SpikeDetectionPanel);
            app.SpikesMindistancebetweenLabel.Text = '(+) Spikes: Min distance between';
            app.PosSpikeMinDistEditField = uieditfield(app.SpikeDetectionPanel, 'numeric');
            app.SpikesMindistancebetweenLabel_2 = uilabel(app.SpikeDetectionPanel);
            app.SpikesMindistancebetweenLabel_2.Text = '(-) Spikes: Min distance between';
            app.NegSpikeMinDistEditField = uieditfield(app.SpikeDetectionPanel, 'numeric');
            app.EventDetectionPanel = uipanel(app.ParametersSettingUIFigure);
            app.EventDetectionPanel.Title = 'Event Detection';
            app.NumSpikesMustHaveEditField = uieditfield(app.EventDetectionPanel, 'numeric');
            app.MusthaveLabel = uilabel(app.EventDetectionPanel);
            app.MusthaveLabel.Text = 'Must have ';
            app.numberofSpikesLabel = uilabel(app.EventDetectionPanel);
            app.numberofSpikesLabel.Text = '(number of) Spikes';
            app.SecondsForSpikesEditField = uieditfield(app.EventDetectionPanel, 'numeric');
            app.InLabel = uilabel(app.EventDetectionPanel);
            app.InLabel.Text = 'In';
            app.numberofSecondsLabel = uilabel(app.EventDetectionPanel);
            app.numberofSecondsLabel.Text = '(number of) Seconds';
            app.MergePosNegEventsButtonGroup = uibuttongroup(app.EventDetectionPanel);
            app.MergePosNegEventsButtonGroup.Title = 'How to merge (+) and (-) events';
            app.UnionEventsButton = uiradiobutton(app.MergePosNegEventsButtonGroup);
            app.UnionEventsButton.Text = 'Union events';
            app.UnionEventsButton.Value = true;
            app.IntersectEventsButton = uiradiobutton(app.MergePosNegEventsButtonGroup);
            app.IntersectEventsButton.Text = 'Intersect events';
            app.OnlyTakePosEventsButton = uiradiobutton(app.MergePosNegEventsButtonGroup);
            app.OnlyTakePosEventsButton.Text = 'Only take (+) events';
            app.OnlyTakeNegEventsButton = uiradiobutton(app.MergePosNegEventsButtonGroup);
            app.OnlyTakeNegEventsButton.Text = 'Only take (-) events';
            app.ConnectEventsCheckBox = uicheckbox(app.EventDetectionPanel);
            app.ConnectEventsCheckBox.Text = '';
            app.Label = uilabel(app.EventDetectionPanel);
            app.Label.Text = 'Connect events that are separated by few wide ';
            app.spikesbutotherwisefollowrulesLabel = uilabel(app.EventDetectionPanel);
            app.spikesbutotherwisefollowrulesLabel.Text = 'spikes but otherwise follow rules';
            app.GlueeventscloserthansEditFieldLabel = uilabel(app.EventDetectionPanel);
            app.GlueeventscloserthansEditFieldLabel.Text = 'Glue events closer than (s)';
            app.GlueEventsEditField = uieditfield(app.EventDetectionPanel, 'numeric');
            app.MinseizurelengthsEditFieldLabel = uilabel(app.EventDetectionPanel);
            app.MinseizurelengthsEditFieldLabel.Text = 'Min seizure length (s)';
            app.MinSeizureLengthEditField = uieditfield(app.EventDetectionPanel, 'numeric');
            app.NoiseDetectionPanel = uipanel(app.ParametersSettingUIFigure);
            app.NoiseDetectionPanel.Title = 'Noise Detection';
            app.IgnorenoiseshorterthansEditFieldLabel = uilabel(app.NoiseDetectionPanel);
            app.IgnorenoiseshorterthansEditFieldLabel.Text = 'Ignore noise shorter than (s)';
            app.IgnoreNoiseEditField = uieditfield(app.NoiseDetectionPanel, 'numeric');
            app.JoinnoiseifcloserthansLabel = uilabel(app.NoiseDetectionPanel);
            app.JoinnoiseifcloserthansLabel.Text = 'Join noise if closer than (s)';
            app.JoinNoiseEditField = uieditfield(app.NoiseDetectionPanel, 'numeric');
            app.DealWithNoiseButtonGroup = uibuttongroup(app.NoiseDetectionPanel);
            app.DealWithNoiseButtonGroup.Title = 'How to deal with noise';
            app.AllowNoiseButton = uiradiobutton(app.DealWithNoiseButtonGroup);
            app.AllowNoiseButton.Text = 'Allow noise';
            app.AllowNoiseButton.Value = true;
            app.SplitEventsAtNoiseLocationsButton = uiradiobutton(app.DealWithNoiseButtonGroup);
            app.SplitEventsAtNoiseLocationsButton.Text = 'Split events at noise locations';
            app.IgnoreEventsContainingNoiseButton = uiradiobutton(app.DealWithNoiseButtonGroup);
            app.IgnoreEventsContainingNoiseButton.Text = 'Ignore events containing noise';
            app.SlidingwindowsizesEditFieldLabel = uilabel(app.NoiseDetectionPanel);
            app.SlidingwindowsizesEditFieldLabel.Text = 'Sliding window size (s)';
            app.SlidingWindowSizeEditField = uieditfield(app.NoiseDetectionPanel, 'numeric');
            app.SlidingwindowstepsEditFieldLabel = uilabel(app.NoiseDetectionPanel);
            app.SlidingwindowstepsEditFieldLabel.Text = 'Sliding window step (s) ';
            app.SlidingWindowStepEditField = uieditfield(app.NoiseDetectionPanel, 'numeric');
            app.RestoreDefaultButton = uibutton(app.ParametersSettingUIFigure, 'push');
            app.RestoreDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultButtonPushed, true);
            app.RestoreDefaultButton.Text = 'Restore Default';
            app.SaveExitButton = uibutton(app.ParametersSettingUIFigure, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.CancelButton = uibutton(app.ParametersSettingUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEGSettingsWin(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParametersSettingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParametersSettingUIFigure)
        end
    end
end