classdef ESA_Extended < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElectrographicSeizureAnalyzerESAUIFigure  matlab.ui.Figure
        ChooseFilesPanel             matlab.ui.container.Panel
        DataDirectoryEditFieldLabel  matlab.ui.control.Label
        DataDirectoryEditField       matlab.ui.control.EditField
        BrowseDataDirButton          matlab.ui.control.Button
        SaveDirectoryEditFieldLabel  matlab.ui.control.Label
        SaveDirectoryEditField       matlab.ui.control.EditField
        BrowseSaveDirButton          matlab.ui.control.Button
        DataFilesListBoxLabel        matlab.ui.control.Label
        DataFilesListBox             matlab.ui.control.ListBox
        ChannelListBoxLabel          matlab.ui.control.Label
        ChannelListBox               matlab.ui.control.ListBox
        LoadButton                   matlab.ui.control.Button
        ConvertFilesButton           matlab.ui.control.Button
        AddFilesButton               matlab.ui.control.Button
        DeleteFilesButton            matlab.ui.control.Button
        CutoffsPanel                 matlab.ui.container.Panel
        BaselinePanel                matlab.ui.container.Panel
        UpperEditFieldLabel          matlab.ui.control.Label
        UpperBaselineEditField       matlab.ui.control.NumericEditField
        LowerEditFieldLabel          matlab.ui.control.Label
        LowerBaselineEditField       matlab.ui.control.NumericEditField
        SpikeCutoffsPanel            matlab.ui.container.Panel
        UpperEditFieldLabel_2        matlab.ui.control.Label
        UpperSpikeCutoffEditField    matlab.ui.control.NumericEditField
        LowerEditField_2Label        matlab.ui.control.Label
        LowerSpikeCutoffEditField    matlab.ui.control.NumericEditField
        ManualChangesButton          matlab.ui.control.Button
        AutomateThisButton           matlab.ui.control.Button
        DebugButton                  matlab.ui.control.Button
        CutoffMethodLabel            matlab.ui.control.Label
        ChannelCutoffsListBoxLabel   matlab.ui.control.Label
        ChannelCutoffsListBox        matlab.ui.control.ListBox
        RunAnalysisPanel             matlab.ui.container.Panel
        DetectNoiseCheckBox          matlab.ui.control.CheckBox
        DetectSeizuresCheckBox       matlab.ui.control.CheckBox
        DisplaysettingsPanel         matlab.ui.container.Panel
        ShowThresholdsCheckBox       matlab.ui.control.CheckBox
        ShowNoiseCheckBox            matlab.ui.control.CheckBox
        ShowCutoffsCheckBox          matlab.ui.control.CheckBox
        ShowSpikesCheckBox           matlab.ui.control.CheckBox
        AnalyzeButton                matlab.ui.control.Button
        SettingsPanel                matlab.ui.container.Panel
        BufferMonitorTextAreaLabel   matlab.ui.control.Label
        BufferMonitorTextArea        matlab.ui.control.TextArea
        OpenSettingsButton           matlab.ui.control.Button
        ForwardButton                matlab.ui.control.Button
        BackButton                   matlab.ui.control.Button
        MainEEGUIPanel               matlab.ui.container.Panel
        MainEEGVoltMinEditField      matlab.ui.control.NumericEditField
        MainEEGVoltMaxEditField      matlab.ui.control.NumericEditField
        MainEEGTimeMaxEditField      matlab.ui.control.NumericEditField
        MainEEGTimeMinEditField      matlab.ui.control.NumericEditField
        ResetAxisButton              matlab.ui.control.Button
        MainEEGSecondUIAxes          matlab.ui.control.UIAxes
        MainEEGFourthUIAxes          matlab.ui.control.UIAxes
        MainEEGUIAxes                matlab.ui.control.UIAxes
        MainEEGThirdUIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % dialog apps
        appDialogSettingWindow; % dialog setting window app
        
        % list of directories and handles
        cFileDirList            % list of file of raw data (*.acq, *.edf, *.mat) directory (cell)
        
        strFileDir              % directory of data files
        strSaveDir              % directory to save data
        %         strSelectedFile         % file selected in the list box that needs to be loaded/converted
        
        % data and header
        objMat                  % pre-load MAT-file object
        sctBuf                  % buffer to hold all the loaded data
        sctHeader               % data header
        sctInfo                 % file information
        pdEEG                   % data
        pdTTL                   % TTL signals for MEA RHD files (not always exists)
        pdResult                % processing result in a format of csv/xsl
        
        % user interface
        objMainGraph            % graph objects
        sctBaseGraph            % nDownSampFactor, pdTimeInMin, pdDownSampData
        objSecondGraph
        objThirdGraph
        objFourthGraph
        
        % status
        bAfterAutoCutoff
        %         bNewProcess             % data process status. 1 - new dataset or new channel; 0 - during processing
        
        
        MainEEGTimeOriginalLimits % added 2/9/2020 by E. Pedapati, original min/maxtime for scrolling functionality
        
    end % properties
    
    properties (Access = public)
        sctSetting;   % structure for setting parameters
    end
    
    methods (Access = private)
        % ----------------------------------- %
        % DATA LOADING AND SAVING
        % ----------------------------------- %
        
        function updateDataDirList(app, cDirList, strAddDel)
            % update cFileDirList in private properties
            % Inputs:   cDirList    - Data directories (path and name) in cell
            %                         array that needs to be added/deleted.
            %                         If no file is selected, this parameter is 0.
            %           strAddDel   - "add" add directories to the list.
            %                         "delete" delete directories from the list
            cTempDirList = app.cFileDirList;
            if length(app.cFileDirList) == 0
                cTempDirList = [];
            end
            
            % add to list
            if strcmp(strAddDel, 'add')
                if iscell(cDirList)
                    
                    for nFileNum = 1 : length(cDirList)
                        % check if the list contains file that is already selected
                        strDir = string(cDirList{nFileNum});
                        pbInd = strcmp(cTempDirList, strDir);    bSelected = max(pbInd);
                        if ~bSelected
                            cTempDirList{end+1} = char(strDir);
                        end
                    end % for nFileNum = 1 : length(cDirList)
                    
                end % if iscell(cDirList)
            end % if strcmp(strAddDel, 'add')
            
            % delete from list
            if strcmp(strAddDel, 'delete')
                if iscell(cDirList)
                    
                    for nFileNum = 1 : length(cDirList)
                        % check if exists in the list
                        strDir = string(cDirList{nFileNum});
                        pbInd = strcmp(cTempDirList, strDir);   bSelected = max(pbInd);
                        if bSelected(1)
                            nInd = find(pbInd == 1);
                            cTempDirList(nInd) = [];
                        end
                    end % for nFileNum = 1 : length(cDirList)
                    
                end % if iscell(cDirList)
            end % if strcmp(strAddDel, 'delete')
            
            app.cFileDirList = cTempDirList;
            
            % refresh data files list box
            cDataDisplayList = cell(length(app.cFileDirList), 1);
            for nFile = 1 : length(app.cFileDirList)
                strDir = string(app.cFileDirList{nFile});
                [~, strName, strExt] = fileparts(strDir);
                strFormat = erase(strExt, '.');
                strNameExt = strcat(strName, strExt);
                cDataDisplayList{nFile} = char(strcat(sprintf('[%s]-', strFormat), strNameExt));
            end % for nFile = 1 : length(app.cFileDirList)
            
            app.DataFilesListBox.Items = cDataDisplayList;
            app.DataFilesListBox.ItemsData = app.cFileDirList;
            
            DataFilesListBoxValueChanged(app);
            
            
        end % function updateDataDirList(app, cDirList, strAddDelete)
        
        function clearPreviousFile(app)
            clearPreviousChannel(app);
            
            %             app.objMat = [];
            app.sctBuf = [];                 % buffer to hold all the loaded data
            app.sctHeader = [];              % data header
            app.pdEEG = [];                  % data
            app.pdTTL = [];                  % TTL signals for MEA RHD files (not always exists)
            
        end
        
        function clearPreviousChannel(app)
            %app.sctInfo = [];                % file information
            app.sctInfo = [struct()];
            %app.pdResult = [];               % processing result in a format of csv/xsl
            app.pdResult = {};               % processing result in a format of csv/xsl
            
            % user interface
            app.objMainGraph = [];           % graph objects
            app.objSecondGraph = [];
            app.objThirdGraph = [];
            app.objFourthGraph = [];
            
            app.sctBaseGraph = [];           % nDownSampFactor, pdTimeInMin, pdDownSampData
            
            % status
            app.bAfterAutoCutoff = 0;
        end
        
        function clearThresholdData(app)
            app.UpperBaselineEditField.Value = 0;
            app.LowerBaselineEditField.Value = 0;
            app.UpperSpikeCutoffEditField.Value = 0;
            app.LowerSpikeCutoffEditField.Value = 0;
            app.ChannelCutoffsListBox.Value = {};
            app.ChannelCutoffsListBox.Items = {};
            app.sctSetting.dBaselineUp = [];
            app.sctSetting.dBaselineLow = [];
            app.sctSetting.dSpikeUp = [];
            app.sctSetting.dSpikeLow = [];
            app.sctSetting.dNoiseUp = [];
            app.sctSetting.dNoiseLow = [];
            app.bAfterAutoCutoff = 0;
        end
    
        function loadDataIntoBuffer(app)
            
        end % function loadDataIntoBuffer(app)
        
        function saveResults(app)
            
            strFile = string(app.DataFilesListBox.Value);
            
            % output directory
            [strDir, strName, ~] = fileparts(strFile);
            
            if (~isempty(app.strSaveDir)) | (app.strSaveDir ~= -1)
                strSave = fullfile(app.strSaveDir, strName);
            else
                strSave = fullfile(strDir, strName);
            end
            mkdir(strSave);
%             
%             cResultHeader = {'Start Date',' ',' ','Start Time',' ',' ','Finish Date',' ',' ','Finish Time',' ',' ','Duration (s)',...
%                 '#+ Spikes', '#- Spikes', '#+W Spikes','#-W Spikes',...
%                 '#+N Spikes','#-N Spikes','Avg(+ Width)','Avg(- Width)',...
%                 '%+N Spikes','%-N Spikes','+N Spikes/s','-N Spikes/s','+ Spikes/s','- Spikes/s',...
%                 'FS','Date Last Analyzed','Starting Sample','Final Sample', 'Starting Sample (Seconds)', 'Final Sample (Seconds)'};
%    
            cResultHeader = {'File Date','Start Date','Finish Date','Relative Start', 'Relative Finish', 'Duration (s)',...
                '#+ Spikes', '#- Spikes', '#+W Spikes','#-W Spikes',...
                '#+N Spikes','#-N Spikes','Avg(+ Width)','Avg(- Width)',...
                '%+N Spikes','%-N Spikes','+N Spikes/s','-N Spikes/s','+ Spikes/s','- Spikes/s',...
                'FS','Date Last Analyzed','Starting Sample','Final Sample', 'Starting Sample (Seconds)', 'Final Sample (Seconds)'};

            for i=1:size(app.pdResult,2)
               % save mat file
                strSaveMat = fullfile(strSave, sprintf('Results_Channel%s.mat', app.ChannelListBox.Value{i}));
                FileDirectory = strFile;
                Info = app.sctInfo(i);

                %format dates | E.P. 7/21/2023 10 AM
%                 app.sctInfo(i).dStartTime = datestr(app.sctInfo(i).dStartTime, 'dd-mmm-yyyy HH:MM:SS');
%                 app.sctInfo(i).dEndTime = datestr(app.sctInfo(i).dEndTime, 'dd-mmm-yyyy HH:MM:SS');
%                 app.sctInfo(i).LastAnalyzed = datestr(app.sctInfo(i).LastAnalyzed, 'dd-mmm-yyyy HH:MM:SS');

                Settings = app.sctSetting;
                Settings.dBaselineUp = Settings.dBaselineUp(i);
                Settings.dBaselineLow = Settings.dBaselineLow(i);
                Settings.dSpikeUp = Settings.dSpikeUp(i);
                Settings.dSpikeLow = Settings.dSpikeLow(i);
                Settings.dNoiseUp = Settings.dNoiseUp(i);
                Settings.dNoiseLow = Settings.dNoiseLow(i);
                Results = app.pdResult{i};
                Results_Events = Results(app.sctInfo(i).EventCategories==1,:);
                Results_No_Event = Results(app.sctInfo(i).EventCategories==2,:);
                Results_Events_W_Noise = Results(app.sctInfo(i).EventCategories==3,:);
                Results_Short_Events = Results(app.sctInfo(i).EventCategories==4,:);
                ResultHeader = cResultHeader;
                save(strSaveMat, 'FileDirectory', 'Info', 'Settings', 'ResultHeader', 'Results');

                % Find the columns with 'Date' in the header
                date_columns = contains(cResultHeader, 'Date');

                % Convert the numerical array to a cell array
                result_cell = num2cell(Results);

                % Convert the date columns to date strings
                for col = find(date_columns)
                    for row = 1:size(Results, 1)
                        result_cell{row, col} = datestr(Results(row, col), 'yyyy-mm-dd HH:MM:SS');
                    end
                end

                Results_Events = result_cell(app.sctInfo(i).EventCategories==1,:);
                Results_No_Event = result_cell(app.sctInfo(i).EventCategories==2,:);
                Results_Events_W_Noise = result_cell(app.sctInfo(i).EventCategories==3,:);
                Results_Short_Events = result_cell(app.sctInfo(i).EventCategories==4,:);
                
                
                %save csv file and xls file for each set of Results
                if ~isempty(Results_Events)
                    strSaveCSV = fullfile(strSave, sprintf('Results_Events_Channel%s.csv', app.ChannelListBox.Value{i}));
                    results_csv = cell2table(Results_Events, 'VariableNames', cResultHeader);
                    writetable(results_csv, strSaveCSV);
                    % (, Results_Events);
                    if ~strcmp(computer,'GLNXA64')
                        strSaveXLS = fullfile(strSave, sprintf('Results_Events_Channel%s.xls', app.ChannelListBox.Value{i}));
                        %cResultAndHeader = [cResultHeader; num2cell(Results_Events)];
                        cResultAndHeader = [cResultHeader; (Results_Events)];

                        if exist(strSaveXLS, 'file')
                            delete(strSaveXLS);
                        end
                        xlswrite(strSaveXLS, cResultAndHeader)
                    end 
                end
            
                if ~isempty(Results_No_Event)
                    strSaveCSV = fullfile(strSave, sprintf('Results_No_Event_Channel%s.csv', app.ChannelListBox.Value{i}));
                    results_csv = cell2table(Results_No_Event, 'VariableNames', cResultHeader); % Typo Fix 9/21/23 from Results_No_Event
                    writetable(results_csv, strSaveCSV);
                    if ~strcmp(computer,'GLNXA64')
                        strSaveXLS = fullfile(strSave, sprintf('Results_No_Event_Channel%s.xls', app.ChannelListBox.Value{i}));
                        %cResultAndHeader = [cResultHeader; num2cell(Results_No_Event)];
                        cResultAndHeader = [cResultHeader; (Results_No_Event)];

                        if exist(strSaveXLS, 'file')
                            delete(strSaveXLS);
                        end
                        xlswrite(strSaveXLS, cResultAndHeader)
                    end 
                end
                
                if ~isempty(Results_Events_W_Noise)
                    strSaveCSV = fullfile(strSave, sprintf('Results_Events_W_Noise_Channel%s.csv', app.ChannelListBox.Value{i}));
                    results_csv = cell2table(Results_Events_W_Noise, 'VariableNames', cResultHeader);
                    writetable(results_csv, strSaveCSV);
                    if ~strcmp(computer,'GLNXA64')
                        strSaveXLS = fullfile(strSave, sprintf('Results_Events_W_Noise_Channel%s.xls', app.ChannelListBox.Value{i}));
                        %cResultAndHeader = [cResultHeader; num2cell(Results_Events_W_Noise)];
                        cResultAndHeader = [cResultHeader; (Results_Events_W_Noise)];

                        if exist(strSaveXLS, 'file')
                            delete(strSaveXLS);
                        end
                        xlswrite(strSaveXLS, cResultAndHeader)
                    end 
                end
                
                if ~isempty(Results_Short_Events)
                    strSaveCSV = fullfile(strSave, sprintf('Results_Short_Events_Channel%s.csv', app.ChannelListBox.Value{i}));
                    results_csv = cell2table(Results_Short_Events, 'VariableNames', cResultHeader);
                    writetable(results_csv, strSaveCSV);
                    if ~strcmp(computer,'GLNXA64')
                        strSaveXLS = fullfile(strSave, sprintf('Results_Short_Events_Channel%s.xls', app.ChannelListBox.Value{i}));
                        %cResultAndHeader = [cResultHeader; num2cell(Results_Short_Events)];
                        cResultAndHeader = [cResultHeader; (Results_Short_Events)];

                        if exist(strSaveXLS, 'file')
                            delete(strSaveXLS);
                        end
                        xlswrite(strSaveXLS, cResultAndHeader)
                    end 
                end


            end


        end
        
        % ----------------------------------- %
        % GRAPH DISPLAY
        % ----------------------------------- %
        
        % display EEG signal in the main window
        function varargout = displayMainEEG(app, nChannel)
                  
            f = waitbar(0, 'Generating EEG graph...', 'Name', 'Generating EEG graph');
            
            dSampRate = app.sctHeader.dSamplingRate(1);
            totalChannels = size(app.pdEEG,2);
            if length(nChannel) >= 4
                pdData = app.pdEEG(:,nChannel);
                [pdDownSampData, nDownSampFactor] = downsampleData(app, pdData, dSampRate);
                pdTimeInMin = (1 : length(pdDownSampData))' * nDownSampFactor / (dSampRate * 60);
    
                %Plot first channel's data on top left plot
                plotEEGValues(app,pdTimeInMin, pdDownSampData(:,1),1,nChannel(1));
                
               %Plot first channel's data on top right plot
                plotEEGValues(app, pdTimeInMin, pdDownSampData(:,2),2,nChannel(2));
                
                %Plot third channel's data on bottom left plot
                plotEEGValues(app, pdTimeInMin, pdDownSampData(:,3),3,nChannel(3));
                
                %Plot fourth channel's data on bottom right plot
                plotEEGValues(app, pdTimeInMin, pdDownSampData(:,4),4,nChannel(4));
                
                %x-axis
                app.MainEEGTimeMinEditField.Value = 0;
                app.MainEEGTimeMaxEditField.Value = ceil(pdTimeInMin(end));
                xlim(app.MainEEGUIAxes, [0, round(1.05 * pdTimeInMin(end))]);
                xlim(app.MainEEGSecondUIAxes, [0, round(1.05 * pdTimeInMin(end))]);
                xlim(app.MainEEGThirdUIAxes, [0, round(1.05 * pdTimeInMin(end))]);
                xlim(app.MainEEGFourthUIAxes, [0, round(1.05 * pdTimeInMin(end))]);
                
                % y-axis
                dMax = max(pdDownSampData); dMin = min(pdDownSampData); dRange = dMax - dMin;
                dLow = (dMax+dMin)/2 - 1.2*dRange/2; dUp = (dMax+dMin)/2 + 1.2*dRange/2;
                ylim(app.MainEEGUIAxes, [dLow(1), dUp(1)]);
                ylim(app.MainEEGSecondUIAxes, [dLow(2), dUp(2)]);
                ylim(app.MainEEGThirdUIAxes, [dLow(3), dUp(3)]);
                ylim(app.MainEEGFourthUIAxes, [dLow(4), dUp(4)]);
                app.MainEEGVoltMinEditField.Value = dLow(1);   app.MainEEGVoltMaxEditField.Value = dUp(1);
               
            elseif length(nChannel) == 3
                clearPlots(app,1);
                pdData = app.pdEEG(:,nChannel);
                [pdDownSampData, nDownSampFactor] = downsampleData(app, pdData, dSampRate);
                pdTimeInMin = (1 : length(pdDownSampData))' * nDownSampFactor / (dSampRate * 60);
                
                plotEEGValues(app,pdTimeInMin, pdDownSampData(:,1),1,nChannel(1));
                plotEEGValues(app,pdTimeInMin, pdDownSampData(:,2),2,nChannel(2));
                plotEEGValues(app,pdTimeInMin, pdDownSampData(:,3),3,nChannel(3));
                
                %x-axis
                app.MainEEGTimeMinEditField.Value = 0;
                app.MainEEGTimeMaxEditField.Value = ceil(pdTimeInMin(end));
                xlim(app.MainEEGUIAxes, [0, round(1.05 * pdTimeInMin(end))]);
                xlim(app.MainEEGSecondUIAxes, [0, round(1.05 * pdTimeInMin(end))]);
                xlim(app.MainEEGThirdUIAxes, [0, round(1.05 * pdTimeInMin(end))]);
                
                % y-axis
                dMax = max(pdDownSampData); dMin = min(pdDownSampData); dRange = dMax - dMin;
                dLow = (dMax+dMin)/2 - 1.2*dRange/2; dUp = (dMax+dMin)/2 + 1.2*dRange/2;
                ylim(app.MainEEGUIAxes, [dLow(1), dUp(1)]);
                ylim(app.MainEEGSecondUIAxes, [dLow(2), dUp(2)]);
                ylim(app.MainEEGThirdUIAxes, [dLow(3), dUp(3)]);
                app.MainEEGVoltMinEditField.Value = dLow(1);   app.MainEEGVoltMaxEditField.Value = dUp(1);
                
            elseif length(nChannel) == 2
                clearPlots(app,2);
                pdData = app.pdEEG(:,nChannel);
                [pdDownSampData, nDownSampFactor] = downsampleData(app, pdData, dSampRate);
                pdTimeInMin = (1 : length(pdDownSampData))' * nDownSampFactor / (dSampRate * 60);
                
                plotEEGValues(app,pdTimeInMin, pdDownSampData(:,1),1,nChannel(1));
                plotEEGValues(app,pdTimeInMin, pdDownSampData(:,2),2,nChannel(2));
                
                %x-axis
                app.MainEEGTimeMinEditField.Value = 0;
                app.MainEEGTimeMaxEditField.Value = ceil(pdTimeInMin(end));
                xlim(app.MainEEGUIAxes, [0, round(1.05 * pdTimeInMin(end))]);
                xlim(app.MainEEGSecondUIAxes, [0, round(1.05 * pdTimeInMin(end))]);
                
                % y-axis
                dMax = max(pdDownSampData); dMin = min(pdDownSampData); dRange = dMax - dMin;
                dLow = (dMax+dMin)/2 - 1.2*dRange/2; dUp = (dMax+dMin)/2 + 1.2*dRange/2;
                ylim(app.MainEEGUIAxes, [dLow(1), dUp(1)]);
                ylim(app.MainEEGSecondUIAxes, [dLow(2), dUp(2)]);
                app.MainEEGVoltMinEditField.Value = dLow(1);   app.MainEEGVoltMaxEditField.Value = dUp(1);
                
            elseif length(nChannel) == 1
                clearPlots(app,3);
                pdData = app.pdEEG(:, nChannel);
                [pdDownSampData, nDownSampFactor] = downsampleData(app, pdData, dSampRate);
                pdTimeInMin = (1 : length(pdDownSampData))' * nDownSampFactor / (dSampRate * 60);
               
                plotEEGValues(app,pdTimeInMin, pdDownSampData,1,nChannel);
                
                %x-axis
                app.MainEEGTimeMinEditField.Value = 0;
                app.MainEEGTimeMaxEditField.Value = ceil(pdTimeInMin(end));
                xlim(app.MainEEGUIAxes, [0, round(1.05 * pdTimeInMin(end))]);
                
                % y-axis
                dMax = max(pdDownSampData); dMin = min(pdDownSampData); dRange = dMax - dMin;
                dLow = (dMax+dMin)/2 - 1.2*dRange/2; dUp = (dMax+dMin)/2 + 1.2*dRange/2;
                ylim(app.MainEEGUIAxes, [dLow, dUp]);
                app.MainEEGVoltMinEditField.Value = double(dLow);   app.MainEEGVoltMaxEditField.Value = double(dUp);
                
            end

            if ~isempty(pdTimeInMin)
                app.MainEEGTimeMinEditField.Value = 0;  app.MainEEGTimeMaxEditField.Value = round(1.05 * pdTimeInMin(end));
                app.MainEEGTimeOriginalLimits = [0, round(1.05 * pdTimeInMin(end))];
            end
            
            xlabel(app.MainEEGUIAxes, 'Time, min');
            xlabel(app.MainEEGSecondUIAxes, 'Time, min');
            xlabel(app.MainEEGThirdUIAxes, 'Time, min');
            xlabel(app.MainEEGFourthUIAxes, 'Time, min');
            
            if any(strcmp(fieldnames(app.objMat),'sctEDFHeader'))
                edfHeader = app.objMat.sctEDFHeader;
                label = edfHeader.label;
                if strcmp('BIO',label)
                    ylabel(app.MainEEGUIAxes, 'Glutamate, uM');
                    ylabel(app.MainEEGSecondUIAxes, 'Glutamate, uM');
                    ylabel(app.MainEEGThirdUIAxes, 'Glutamate, uM');
                    ylabel(app.MainEEGFourthUIAxes, 'Glutamate, uM');
                else
                    ylabel(app.MainEEGUIAxes, 'Voltage');
                    ylabel(app.MainEEGSecondUIAxes, 'Voltage');
                    ylabel(app.MainEEGThirdUIAxes, 'Voltage');
                    ylabel(app.MainEEGFourthUIAxes, 'Voltage');
                end
            else
                ylabel(app.MainEEGUIAxes, 'Voltage');
                ylabel(app.MainEEGSecondUIAxes, 'Voltage');
                ylabel(app.MainEEGThirdUIAxes, 'Voltage');
                ylabel(app.MainEEGFourthUIAxes, 'Voltage');
            end
            clear dMax dMin dLow dUp dRange;
            waitbar(0.67, f, 'Generating EEG graph...');
            
            close(f);
            app.BackButton.Enable = 'on';
            app.ForwardButton.Enable = 'on';
            app.ResetAxisButton.Enable = 'on';
            % update 2/9/2020 by E. Pedapati
            % add object property for original min-max limits
            
            %app.MainEEGTimeOriginalLimits = [0, round(1.05 * pdTimeInMin(end))];
            
            if nargout > 0
                varargout{1} = nDownSampFactor;
            end
            
            if nargout > 1
                varargout{2} = pdTimeInMin; % pdLine
            end
            
            if nargout > 2
                varargout{3} = pdDownSampData;
            end
            
        end % displayMainEEG(app)
    
        function plotEEGValues(app,pdTimeInMin, pdDownSampData,plotValue,nChannel)
            switch plotValue
                case 1
                    hold(app.MainEEGUIAxes, 'on');
                    plot(app.MainEEGUIAxes, pdTimeInMin, pdDownSampData);
                    plot(app.MainEEGUIAxes, pdTimeInMin, zeros(length(pdTimeInMin), 1), 'k--');
                    app.MainEEGUIAxes.Title.String = sprintf('Channel %d Signal',nChannel);
                    hold(app.MainEEGUIAxes, 'off');
                case 2
                    hold(app.MainEEGSecondUIAxes, 'on');
                    plot(app.MainEEGSecondUIAxes, pdTimeInMin, pdDownSampData);
                    plot(app.MainEEGSecondUIAxes, pdTimeInMin, zeros(length(pdTimeInMin), 1), 'k--');
                    app.MainEEGSecondUIAxes.Title.String = sprintf('Channel %d Signal',nChannel);
                    hold(app.MainEEGSecondUIAxes, 'off');
                case 3
                    hold(app.MainEEGThirdUIAxes, 'on');
                    plot(app.MainEEGThirdUIAxes, pdTimeInMin, pdDownSampData);
                    plot(app.MainEEGThirdUIAxes, pdTimeInMin, zeros(length(pdTimeInMin), 1), 'k--');
                    app.MainEEGThirdUIAxes.Title.String = sprintf('Channel %d Signal',nChannel);
                    hold(app.MainEEGThirdUIAxes, 'off');
                case 4
                    hold(app.MainEEGFourthUIAxes, 'on');
                    plot(app.MainEEGFourthUIAxes, pdTimeInMin, pdDownSampData);
                    plot(app.MainEEGFourthUIAxes, pdTimeInMin, zeros(length(pdTimeInMin), 1), 'k--');
                    app.MainEEGFourthUIAxes.Title.String = sprintf('Channel %d Signal',nChannel);
                    hold(app.MainEEGFourthUIAxes, 'off');
                otherwise
                    %do nothing
            end
        end
    
    
        function cutoffUpdate(app, channelNum)
                [~,value_index] = ismember(channelNum, app.ChannelCutoffsListBox.Items);
                app.sctSetting.dBaselineUp(value_index)  = app.UpperBaselineEditField.Value;
                app.sctSetting.dBaselineLow(value_index)     = app.LowerBaselineEditField.Value;
                app.sctSetting.dSpikeUp(value_index)         = app.UpperSpikeCutoffEditField.Value;
                app.sctSetting.dSpikeLow(value_index)        = app.LowerSpikeCutoffEditField.Value;
                app.sctSetting.strCutoffMethod  = 'Manual';
                                
                app.sctInfo(value_index).Autocutoffs.dBaselineUp      = app.UpperBaselineEditField.Value;
                app.sctInfo(value_index).Autocutoffs.dBaselineLow     = app.LowerBaselineEditField.Value;
                app.sctInfo(value_index).Autocutoffs.dSpikeUp         = app.UpperSpikeCutoffEditField.Value;
                app.sctInfo(value_index).Autocutoffs.dSpikeLow        = app.LowerSpikeCutoffEditField.Value;
                app.sctInfo(value_index).Autocutoffs.strCutoffMethod  = 'Manual';
            
        end
    
        function plotReset(app)
                app.MainEEGTimeMinEditField.Value = 0;
                app.MainEEGTimeMaxEditField.Value = 0;
                
                app.MainEEGVoltMinEditField.Value = -500;   app.MainEEGVoltMaxEditField.Value = 500;
                
                xlim(app.MainEEGUIAxes, [0, 1]);
                ylim(app.MainEEGUIAxes, [-500, 500]);
                
                xlim(app.MainEEGSecondUIAxes, [0, 1]);
                ylim(app.MainEEGSecondUIAxes, [-500, 500]);
                
                xlim(app.MainEEGThirdUIAxes, [0, 1]);
                ylim(app.MainEEGThirdUIAxes, [-500, 500]);
                
                xlim(app.MainEEGFourthUIAxes, [0, 1]);
                ylim(app.MainEEGFourthUIAxes, [-500, 500]);
                
               
        end   
        

        function graph=plotRefresh(app,graph,plotNum,pdLine,pdTimeInMin)
            switch plotNum
                    case 1
                        plotFig = app.MainEEGUIAxes;
                    case 2
                        plotFig = app.MainEEGSecondUIAxes;
                    case 3
                        plotFig = app.MainEEGThirdUIAxes;
                    case 4
                        plotFig = app.MainEEGFourthUIAxes;
            end
            hold(plotFig, 'on');
            if length(app.sctSetting.dBaselineUp) >= plotNum
                graph.pBU = [];
                if ~isempty(app.sctSetting.dBaselineUp(plotNum))
                    temp_BaselineUp = app.sctSetting.dBaselineUp(plotNum) * pdLine;
                    if size(pdLine,2) > 1
                        graph.pBU = line(plotFig,pdTimeInMin, temp_BaselineUp(1,:),'Color','m');
                    elseif size(pdLine,2) == 1
                        graph.pBU = line(plotFig,pdTimeInMin, temp_BaselineUp,'Color','m');
                    end
                    temp_BaselineUp = [];
                end
            
                graph.pBL = [];
                if ~isempty(app.sctSetting.dBaselineLow(plotNum))
                    temp_BaselineLow = app.sctSetting.dBaselineLow(plotNum) * pdLine;
                    if size(pdLine,2) > 1
                        graph.pBL = line(plotFig, pdTimeInMin, temp_BaselineLow(1,:),'Color','m');
                    elseif size(pdLine,2) == 1
                        graph.pBL = line(plotFig, pdTimeInMin, temp_BaselineLow,'Color','m');
                    end
                    temp_BaselineLow = [];
                end
                
                graph.pSU = [];
                if ~isempty(app.sctSetting.dSpikeUp(plotNum))
                    temp_SpikeUp = app.sctSetting.dSpikeUp(plotNum) * pdLine;
                    if size(pdLine,2) > 1
                        graph.pSU = line(plotFig, pdTimeInMin, temp_SpikeUp(1,:),'Color', 'y');
                    elseif size(pdLine,2) == 1
                        graph.pSU = line(plotFig, pdTimeInMin, temp_SpikeUp,'Color', 'y');
                    end
                    temp_SpikeUp = [];
                end
                
                graph.pSL = [];
                if ~isempty(app.sctSetting.dSpikeLow(plotNum))
                    temp_SpikeLow = app.sctSetting.dSpikeLow(plotNum) * pdLine;
                    if size(pdLine,2) > 1
                        graph.pSL = line(plotFig, pdTimeInMin, temp_SpikeLow(1,:),'Color','y');
                    elseif size(pdLine,2) == 1
                        graph.pSL = line(plotFig, pdTimeInMin, temp_SpikeLow,'Color','y');
                    end
                    temp_SpikeLow = [];                    
                end
            end
            hold(plotFig, 'off');
            
        end
        function graph=plotSpikes(app,graph,plotNum,psn,nsn,psw,nsw,domain,nDownSampFactor)
                switch plotNum
                    case 1
                        plotFig = app.MainEEGUIAxes;
                    case 2
                        plotFig = app.MainEEGSecondUIAxes;
                    case 3
                        plotFig = app.MainEEGThirdUIAxes;
                    case 4
                        plotFig = app.MainEEGFourthUIAxes;
                end
             
                hold(plotFig,'on'); 
                graph.pPSN =[];
                if ~isempty(psn)
                    psn(:, 1) = ceil(psn(:, 1) / nDownSampFactor);
                    graph.pPSN = plot(plotFig, domain(psn(:,1)), psn(:,2),'k.');
                end
                
                graph.pPSN = [];
                if ~isempty(nsn)
                    nsn(:, 1) = ceil(nsn(:, 1) / nDownSampFactor);
                    graph.pNSN = plot(plotFig, domain(nsn(:,1)), nsn(:,2),'k.');
                end
                
                graph.pPSW = [];
                if ~isempty(psw)
                    psw(:, 1) = ceil(psw(:, 1) / nDownSampFactor);
                    graph.pPSW = plot(plotFig, domain(psw(:,1)), psw(:,2),'m.');
                end
                
                graph.NSW = [];
                if ~isempty(nsw)
                    nsw(:, 1) = ceil(nsw(:, 1) / nDownSampFactor);
                    graph.pNSW = plot(plotFig, domain(nsw(:,1)), nsw(:,2),'m.');
                end
                hold(plotFig, 'off');
        end
    
        function graph=plotEvents(app,graph,plotNum,posevents, negevents, combinedevents,domain,nDownSampFactor)
            switch plotNum
                    case 1
                        plotFig = app.MainEEGUIAxes;
                    case 2
                        plotFig = app.MainEEGSecondUIAxes;
                    case 3
                        plotFig = app.MainEEGThirdUIAxes;
                    case 4
                        plotFig = app.MainEEGFourthUIAxes;
            end
            hold(plotFig,'on');
            graph.pPositive = [];
            if ~isempty(posevents)
                posevents = ceil(posevents / nDownSampFactor);
                loc = 0.5 * app.sctSetting.dBaselineUp(plotNum);     % Info.Settings.BaselineUpper;
                graph.pPositive = plot(plotFig, [domain(posevents(:,1)) domain(posevents(:,2))], [loc loc],'g','LineWidth',2);
            end
            
            graph.pNegative = [];
            if ~isempty(negevents)
                negevents = ceil(negevents / nDownSampFactor);
                loc = 0.5 * app.sctSetting.dBaselineLow(plotNum);    % Info.Settings.BaselineLower;
                graph.pNegative = plot(plotFig, [domain(negevents(:,1)) domain(negevents(:,2))], [loc loc],'r','LineWidth',2);
            end
            
            graph.pCombined = [];
            if ~isempty(combinedevents)
                combinedevents = ceil(combinedevents / nDownSampFactor);
                loc = 0.2 * app.sctSetting.dBaselineUp(plotNum);     % Info.Settings.BaselineUpper;
                graph.pCombined = plot(plotFig, [domain(combinedevents(:,1)) domain(combinedevents(:,2))], [loc loc],'m','LineWidth',2);
            end
            hold(plotFig,'off');
            
            
        end
    
    
        function graph=plotNoise(app,graph,plotNum, noise, domain,nDownSampFactor)
            switch plotNum
                    case 1
                        plotFig = app.MainEEGUIAxes;
                    case 2
                        plotFig = app.MainEEGSecondUIAxes;
                    case 3
                        plotFig = app.MainEEGThirdUIAxes;
                    case 4
                        plotFig = app.MainEEGFourthUIAxes;
            end
            hold(plotFig,'on');
            if ~isempty(noise)
                noise = ceil(noise / nDownSampFactor);
                graph.pNoise = plot(plotFig, [domain(noise(:,1)) domain(noise(:,2))],[0 0],'y','LineWidth',2);
            end
            hold(plotFig,'off'); 
            
        end
    
        function clearPlots(app,numAxes)
            switch numAxes
                case 4
                    cla(app.MainEEGFourthUIAxes);
                    app.MainEEGFourthUIAxes.Title.String = '';
                    cla(app.MainEEGThirdUIAxes);
                    app.MainEEGThirdUIAxes.Title.String = '';
                    cla(app.MainEEGSecondUIAxes); 
                    app.MainEEGSecondUIAxes.Title.String = '';
                    cla(app.MainEEGUIAxes);
                    app.MainEEGUIAxes.Title.String = '';
                case 3
                    cla(app.MainEEGFourthUIAxes);
                    app.MainEEGFourthUIAxes.Title.String = '';
                    cla(app.MainEEGThirdUIAxes);
                    app.MainEEGThirdUIAxes.Title.String = '';
                    cla(app.MainEEGSecondUIAxes); 
                    app.MainEEGSecondUIAxes.Title.String = '';
                    
                case 2
                    cla(app.MainEEGFourthUIAxes);
                    app.MainEEGFourthUIAxes.Title.String = '';
                    cla(app.MainEEGThirdUIAxes); 
                    app.MainEEGThirdUIAxes.Title.String = '';
                    
                case 1
                   
                    cla(app.MainEEGFourthUIAxes);
                    app.MainEEGUIAxes.Title.String = '';
                    
                otherwise
                    %do nothing
                    
            end
        end
    
        function clearThresholdLines(app,numAxes)
                switch numAxes
                    case 4
                        if isprop(app,'objFourthGraph')
                            if ~isempty(app.objFourthGraph)  
                                setVisibility(app,app.objFourthGraph,'threshold');
                                app.objFourthGraph = emptyPlotAttributes(app,app.objFourthGraph,'threshold');
                            end
                        end
                        
                    case 3
                        if isprop(app,'objThirdGraph')
                            if ~isempty(app.objThirdGraph)  
                                setVisibility(app,app.objThirdGraph,'threshold');
                                app.objThirdGraph = emptyPlotAttributes(app,app.objThirdGraph,'threshold');
                            end
                        end
                    case 2
                        if isprop(app,'objSecondGraph')
                            if ~isempty(app.objSecondGraph)  
                                setVisibility(app,app.objSecondGraph,'threshold');
                                app.objSecondGraph = emptyPlotAttributes(app,app.objSecondGraph,'threshold');
                            end  
                        end
                    case 1
                        if isprop(app,'objMainGraph')
                            if ~isempty(app.objMainGraph)    
                                setVisibility(app,app.objMainGraph,'threshold');
                                app.objMainGraph = emptyPlotAttributes(app,app.objMainGraph,'threshold');
                            end
                        end
                    otherwise
                        %do nothing
                        
                end
        end
    
        function clearAnalysis(app,numAxes)
                switch numAxes
                    case 4
                        if isprop(app,'objFourthGraph')
                            if ~isempty(app.objFourthGraph)  
                                setVisibility(app,app.objFourthGraph,'analysis');
                                app.objFourthGraph = emptyPlotAttributes(app,app.objFourthGraph,'analysis');
                            end
                        end
                        
                    case 3
                        if isprop(app,'objThirdGraph')
                            if ~isempty(app.objThirdGraph)  
                                setVisibility(app,app.objThirdGraph,'analysis');
                                app.objThirdGraph = emptyPlotAttributes(app,app.objThirdGraph,'analysis');
                            end
                        end
                    case 2
                        if isprop(app,'objSecondGraph')
                            if ~isempty(app.objSecondGraph)  
                                setVisibility(app,app.objSecondGraph,'analysis');
                                app.objSecondGraph = emptyPlotAttributes(app,app.objSecondGraph,'analysis');
                            end  
                        end
                    case 1
                        if isprop(app,'objMainGraph')
                            if ~isempty(app.objMainGraph)    
                                setVisibility(app,app.objMainGraph,'analysis');
                                app.objMainGraph = emptyPlotAttributes(app,app.objMainGraph,'analysis');
                            end
                        end
                    otherwise
                        %do nothing
                        
                end
        end
        function setVisibility(app, graph, mode)
            if strcmp(mode, 'threshold')
                if ~isempty(graph.pBU)
                    set(graph.pBU, 'Visible', 'off');
                end
                if ~isempty(graph.pBL)
                    set(graph.pBL, 'Visible', 'off');
                end
                if ~isempty(graph.pSU)
                    set(graph.pSU, 'Visible', 'off');
                end
                if ~isempty(graph.pSL)
                    set(graph.pSL, 'Visible', 'off');
                end
            elseif strcmp(mode, 'analysis')
                if isfield(graph,'pPSN')
                    if ~isempty(graph.pPSN)
                        set(graph.pPSN, 'Visible', 'off');
                    end
                end
                
                if isfield(graph,'pPSW')
                    if ~isempty(graph.pPSW)
                        set(graph.pPSW, 'Visible', 'off');
                    end
                end
                
                if isfield(graph,'pNSW')
                    if ~isempty(graph.pNSW)
                        set(graph.pNSW, 'Visible', 'off');
                    end
                end
                
                if isfield(graph,'pNSN')
                    if ~isempty(graph.pNSN)
                        set(graph.pNSN, 'Visible', 'off');
                    end
                end
                
                if isfield(graph,'pPositive')
                    if ~isempty(graph.pPositive)
                        set(graph.pPositive, 'Visible', 'off');
                    end
                end
                
                if isfield(graph,'pNegative')
                    if ~isempty(graph.pNegative)
                        set(graph.pNegative, 'Visible', 'off');
                    end
                end
                
                if isfield(graph,'pCombined')
                    if ~isempty(graph.pCombined)
                        set(graph.pCombined, 'Visible', 'off');
                    end   
                end 
            end
        end
        function graph = emptyPlotAttributes(app,graph,mode)
            if strcmp(mode,'threshold')
                graph.pBU = [];
                graph.pBL = [];
                graph.pSU = [];
                graph.pSL = [];
            elseif strcmp(mode, 'analysis')
                graph.pPSN = [];
                graph.pPSW = [];
                graph.pNSW = [];
                graph.pNSN = [];
                graph.pPositive = [];
                graph.pNegative = [];
            end
        end
        function refreshMainEEG(app)
         
            
            try
                nDownSampFactor = app.sctBaseGraph.nDownSampFactor;
                pdTimeInMin = app.sctBaseGraph.pdTimeInMin;
                pdLine = ones(length(pdTimeInMin));
                nChannel = str2double(app.ChannelListBox.Value);
            catch
                % add threshold lines
                nChannel = str2double(app.ChannelListBox.Value);
                if length(app.sctHeader.dSamplingRate) > 1
                    dSampRate = app.sctHeader.dSamplingRate(nChannel);
                else
                    dSampRate = app.sctHeader.dSamplingRate(1);
                end
                pdLine = ones(length(app.pdEEG(:, nChannel)), 1);
                
                [pdLine, nDownSampFactor] = downsampleData(app, pdLine, dSampRate);
                pdTimeInMin = (1 : length(pdLine))' * nDownSampFactor / (dSampRate * 60);  % x-axis in time
            end
            
            if app.ShowCutoffsCheckBox.Value
                                
                
                if length(nChannel) >= 4
                    clearThresholdLines(app,1);
                    app.objMainGraph=plotRefresh(app,app.objMainGraph,1,pdLine,pdTimeInMin);
                    clearThresholdLines(app,2);
                    app.objSecondGraph=plotRefresh(app,app.objSecondGraph,2,pdLine,pdTimeInMin);
                    clearThresholdLines(app,3);
                    app.objThirdGraph=plotRefresh(app,app.objThirdGraph,3,pdLine,pdTimeInMin);
                    clearThresholdLines(app,4);
                    app.objFourthGraph=plotRefresh(app,app.objFourthGraph,4,pdLine,pdTimeInMin);
                elseif length(nChannel) == 3
                    clearThresholdLines(app,1);
                    app.objMainGraph=plotRefresh(app,app.objMainGraph,1,pdLine,pdTimeInMin);
                    clearThresholdLines(app,2);
                    app.objSecondGraph=plotRefresh(app,app.objSecondGraph,2,pdLine,pdTimeInMin);
                    clearThresholdLines(app,3);
                    app.objThirdGraph=plotRefresh(app,app.objThirdGraph,3,pdLine,pdTimeInMin);
                elseif length(nChannel) == 2
                    clearThresholdLines(app,1);
                    app.objMainGraph=plotRefresh(app,app.objMainGraph,1,pdLine,pdTimeInMin);
                    clearThresholdLines(app,2);
                    app.objSecondGraph=plotRefresh(app,app.objSecondGraph,2,pdLine,pdTimeInMin);
                elseif length(nChannel) == 1
                    clearThresholdLines(app,1);
                    app.objMainGraph=plotRefresh(app,app.objMainGraph,1,pdLine,pdTimeInMin);
                else
                    %do nothing
                end
                %             else % ~app.ShowCutoffsCheckBox.Value
            else
                if app.bAfterAutoCutoff
                    clearThresholdLines(app,1);
                    clearThresholdLines(app,2);
                    clearThresholdLines(app,3);
                    clearThresholdLines(app,4);
                end
            end % if app.ShowCutoffsCheckBox.Value
        end % function  refreshMainEEG(app)


        function displayAnalysis(app)
            
            try
                nDownSampFactor = app.sctBaseGraph.nDownSampFactor;
                pdTimeInMin = app.sctBaseGraph.pdTimeInMin;
                %                 pdLine = ones(length(pdTimeInMin));
            catch
                % add threshold lines
                nChannel = str2double(app.ChannelListBox.Value);
                dSampRate = app.sctHeader.dSamplingRate(nChannel);
                pdLine = ones(length(app.pdEEG(:, nChannel)), 1);
                
                [pdLine, nDownSampFactor] = downsampleData(app, pdLine, dSampRate);
                pdTimeInMin = (1 : length(pdLine))' * nDownSampFactor / (dSampRate * 60);  % x-axis in time
            end
            
            domain = pdTimeInMin;
            posevents = {app.sctInfo(:).EventsPos};
            negevents = {app.sctInfo(:).EventsNeg};
            combinedevents = {app.sctInfo(:).EventsCombined};
            spikeInfo = [app.sctInfo(:).Spikes];
            noise = cellfun(@rdivide,{app.sctInfo.Noise},num2cell(repelem(nDownSampFactor,size(app.sctInfo,2))),'UniformOutput',false);
            psn = {spikeInfo.pnPosSpikesNarrow};
            nsn = {spikeInfo.pnNegSpikesNarrow};
            psw = {spikeInfo.pnPosSpikesWide};
            nsw = {spikeInfo.pnNegSpikesWide};
            
            % Add spikes to plot (if desired)
            % Black dots for regular spikes, pink dots for wide spikes
            if size(app.pdResult,2)>=4
                    clearAnalysis(app,1);
                    clearAnalysis(app,2);
                    clearAnalysis(app,3);
                    clearAnalysis(app,4);
                    if app.ShowSpikesCheckBox.Value
                        app.objMainGraph=plotSpikes(app,app.objMainGraph,1,psn{1},nsn{1},psw{1},nsw{1},domain,nDownSampFactor);
                        app.objSecondGraph=plotSpikes(app,app.objSecondGraph,2,psn{2},nsn{2},psw{2},nsw{2},domain,nDownSampFactor);
                        app.objThirdGraph=plotSpikes(app,app.objThirdGraph,3,psn{3},nsn{3},psw{3},nsw{3},domain,nDownSampFactor);
                        app.objFourthGraph=plotSpikes(app,app.objFourthGraph,4,psn{4},nsn{4},psw{4},nsw{4},domain,nDownSampFactor);
                    end 
                    
                    app.objMainGraph=plotEvents(app,app.objMainGraph,1,posevents{1},negevents{1},combinedevents{1},domain,nDownSampFactor);
                    app.objSecondGraph=plotEvents(app,app.objSecondGraph,2,posevents{2},negevents{2},combinedevents{2},domain,nDownSampFactor);
                    app.objThirdGraph=plotEvents(app,app.objThirdGraph,3,posevents{3},negevents{3},combinedevents{3},domain,nDownSampFactor);
                    app.objFourthGraph=plotEvents(app,app.objFourthGraph,4,posevents{4},negevents{4},combinedevents{4},domain,nDownSampFactor);
                    
                    app.objMainGraph=plotNoise(app,app.objMainGraph,1,noise{1},nDownSampFactor);
                    app.objSecondGraph=plotNoise(app,app.objSecondGraph,2,noise{2},nDownSampFactor);
                    app.objThirdGraph=plotNoise(app,app.objThirdGraph,3,noise{3},nDownSampFactor);
                    app.objFourthGraph=plotNoise(app,app.objFourthGraph,4,noise{4},nDownSampFactor);
                    
            elseif size(app.pdResult,2) == 3
                    clearAnalysis(app,1);
                    clearAnalysis(app,2);
                    clearAnalysis(app,3);
                    if app.ShowSpikesCheckBox.Value
                        app.objMainGraph=plotSpikes(app,app.objMainGraph,1,psn{1},nsn{1},psw{1},nsw{1},domain,nDownSampFactor);
                        app.objSecondGraph=plotSpikes(app,app.objSecondGraph,2,psn{2},nsn{2},psw{2},nsw{2},domain,nDownSampFactor);
                        app.objThirdGraph=plotSpikes(app,app.objThirdGraph,3,psn{3},nsn{3},psw{3},nsw{3},domain,nDownSampFactor);
                    end 
                    
                    app.objMainGraph=plotEvents(app,app.objMainGraph,1,posevents{1},negevents{1},combinedevents{1},domain,nDownSampFactor);
                    app.objSecondGraph=plotEvents(app,app.objSecondGraph,2,posevents{2},negevents{2},combinedevents{2},domain,nDownSampFactor);
                    app.objThirdGraph=plotEvents(app,app.objThirdGraph,3,posevents{3},negevents{3},combinedevents{3},domain,nDownSampFactor);
                    
                    app.objMainGraph=plotNoise(app,app.objMainGraph,1,noise{1},domain,nDownSampFactor);
                    app.objSecondGraph=plotNoise(app,app.objSecondGraph,2,noise{2},domain,nDownSampFactor);
                    app.objThirdGraph=plotNoise(app,app.objThirdGraph,3,noise{3},domain,nDownSampFactor);
                        
            elseif size(app.pdResult,2) == 2
                    clearAnalysis(app,1);
                    clearAnalysis(app,2);
                    if app.ShowSpikesCheckBox.Value
                        app.objMainGraph=plotSpikes(app,app.objMainGraph,1,psn{1},nsn{1},psw{1},nsw{1},domain,nDownSampFactor);
                        app.objSecondGraph=plotSpikes(app,app.objSecondGraph,2,psn{2},nsn{2},psw{2},nsw{2},domain,nDownSampFactor);
                    end
                    app.objMainGraph=plotEvents(app,app.objMainGraph,1,posevents{1},negevents{1},combinedevents{1},domain,nDownSampFactor);
                    app.objSecondGraph=plotEvents(app,app.objSecondGraph,2,posevents{2},negevents{2},combinedevents{2},domain,nDownSampFactor);
                    
                    app.objMainGraph=plotNoise(app,app.objMainGraph,1,noise{1},domain,nDownSampFactor);
                    app.objSecondGraph=plotNoise(app,app.objSecondGraph,2,noise{2},domain,nDownSampFactor);
 
            else
                    clearAnalysis(app,1);
                    if app.ShowSpikesCheckBox.Value
                        app.objMainGraph=plotSpikes(app,app.objMainGraph,1,psn{1},nsn{1},psw{1},nsw{1},domain,nDownSampFactor);
                    end
                    
                    app.objMainGraph=plotEvents(app,app.objMainGraph,1,posevents{1},negevents{1},combinedevents{1},domain,nDownSampFactor);
                    
                    app.objMainGraph=plotNoise(app,app.objMainGraph,1,noise{1},domain,nDownSampFactor);

                
            end  % if ShowSpikes           
        end
        
        % ----------------------------------- %
        % COMPUTATION AND PROCESSING
        % ----------------------------------- %
        
        % downsample the data (mainly for fast graph display)
        function [pdDownSampData, varargout] = downsampleData(app, pdData, dSampRate)
            nDownSampFactor = round(dSampRate / 50);
            if isequal(nDownSampFactor, 0)
                nDownSampFactor = 1;
                pdDownSampData = pdData;
                if nargout > 1
                    varargout{1} = nDownSampFactor;
                end
            else
                pdDownSampData = downsample(pdData, nDownSampFactor);
                if nargout > 1
                    varargout{1} = nDownSampFactor;
                end
            end
            
        end
        
        % data analysis
        function analyzeData(app)
            waitbarEnabled = true;
            nChannel = str2double(app.ChannelListBox.Value);
            pdData = app.pdEEG(:, nChannel);
            if length(app.sctHeader.dSamplingRate) == 1
                dSampRate = app.sctHeader.dSamplingRate;
            else
                dSampRate = app.sctHeader.dSamplingRate(nChannel);
            end
            %begin parallel pool, using MATLAB parallel pool, to process events
            if isempty(gcp('nocreate'))
                parpool('local');
            end
            % find all spike information
            sctSpikes = findSpikes(pdData, dSampRate, app.sctSetting, 0);
            for n=1:length(nChannel)
                app.sctInfo(n).Spikes = structfun(@(x) x{n}, sctSpikes, 'Uni', false);
            end
            posspikes = sctSpikes.pnPosSpikes;
            negspikes = sctSpikes.pnNegSpikes;
            psn = sctSpikes.pnPosSpikesNarrow;
            nsn = sctSpikes.pnNegSpikesNarrow;
            psw = sctSpikes.pnPosSpikesWide;
            nsw = sctSpikes.pnNegSpikesWide;
%             [app.sctInfo.Spikes.pnPosSpikes] = posspikes{:};
%             [app.sctInfo.Spikes.pnNegSpikes] = negspikes{:};
%             [app.sctInfo.Spikes.pnPosSpikesNarrow] = psn{:};
%             [app.sctInfo.Spikes.pnNegSpikesNarrow] = nsn{:};
%             [app.sctInfo.Spikes.pnPosSpikesWide] = psw{:};
%             [app.sctInfo.Spikes.pnNegSpikesWide] = nsw{:};
            % find all noise
            if app.DetectNoiseCheckBox.Value
                pnNoise = calculateNoise(pdData, app.sctInfo, dSampRate, app.sctSetting, app.sctBaseGraph.nDownSampFactor, app.ShowNoiseCheckBox.Value);
            else
                pnNoise = [];
            end
            
            [app.sctInfo.Noise] = pnNoise{:};
            
            evt_stats=[];
            evt_stats_with_header=[];
            
            % find all seizure events
            if app.DetectSeizuresCheckBox.Value % if DoEvents
                [pnPosEvents, pnNegEvents] = seekEvents(pdData, app.sctInfo, dSampRate, app.sctSetting, 0);
                  
                 [app.sctInfo.EventsPos] = pnPosEvents{:};   % store events
                 [app.sctInfo.EventsNeg] = pnNegEvents{:};

                
                % combine positive and negative events, and incorporate
                % noise 
                eventcutoff = app.sctSetting.EventDetection.dMinSeizureLength * dSampRate;
                noisecutoff = app.sctSetting.NoiseDetection.dIgnoreNoise * dSampRate;
                eventglue   = app.sctSetting.EventDetection.dGlueEvents * dSampRate;
                dwn         = app.sctSetting.NoiseDetection.DealWithNoise.Value;
                dwm         = app.sctSetting.EventDetection.MergePosNegEvents.Value;
                % Ernie and Manolia changed pnPosEvents to pnNegEvents for
                % the second input
                [pnCombinedEvents, pnNoise] = connectEvents(pnPosEvents, pnNegEvents, pnNoise, dwm, dwn, eventcutoff, noisecutoff, eventglue);
                [app.sctInfo.Noise] = pnNoise{:};    % update noise after removing short ones
                [app.sctInfo.EventsCombined] = pnCombinedEvents{:}; % combinedevents
                
                if isfield(app.sctHeader,'trdata')
                    dTimeStamps = app.sctHeader.trdata.timestamp;
                else
                    dTimeStamps = [app.objMat.t_amplifier(1,1),app.objMat.t_amplifier(1,end)];
                end
                temp_start = num2cell(repelem(dTimeStamps(1),size(pdData,2)));
                [app.sctInfo.dStartTime] = temp_start{:};
                temp_end = num2cell(repelem(dTimeStamps(end),size(pdData,2)));
                [app.sctInfo.dEndTime] = temp_end{:};
                temp_start = [];
                temp_end = [];
                
                filestart = app.sctInfo.dStartTime;  % Info.FileInfo(FileNumber).StartTime;
                fileend = app.sctInfo.dEndTime;  % Info.FileInfo(FileNumber).EndTime;
                filelength = etime(datevec(fileend),datevec(filestart));
                fs = dSampRate;
                
                % adding waitbar functionality by E. Pedapati 2/9/2020
%                 if waitbarEnabled == true
%                     eventF = waitbar(0, 'Processing Events','Name','Analyzing Events...',...
%                         'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
%                     setappdata(eventF,'canceling',0);
%                 end
                for j=1:size(pdData,2)
                    temp_pnCombinedEvents = pnCombinedEvents{j};
                    nr_of_events = size(temp_pnCombinedEvents,1);
                    if length(fs) > 1
                        fs = fs(j);
                    end
                    for i=1:nr_of_events
                        % adding waitbar functionality by E. Pedapati 2/9/2020
%                         if waitbarEnabled == true
%                             if getappdata(eventF,'canceling')
%                                 break;
%                             end
%                             waitbar(i/nr_of_events,eventF,sprintf('Events %d of %d', i, nr_of_events));
%                         end
                        time_start = filestart + app.sctInfo(j).EventsCombined / (24*60*60*fs); %start time
                        [y_start,m_start,d_start,h_start,mn_start,s_start] = datevec(time_start(i,1)); %components
                        time_finish = filestart + app.sctInfo(j).EventsCombined / (24*60*60*fs); %end time
                        [y_finish,m_finish,d_finish,h_finish,mn_finish,s_finish] = datevec(time_finish(i,2)); %components
                        duration = etime(datevec(time_finish(i,2)),datevec(time_start(i,1))); 

      
%                         % edited by EP for streamline date format 
%                         filestart_f = app.convertDateForExcel(filestart);
%                         time_start_f = app.convertDateForExcel(time_start(i,1));
%                         time_finish_f = app.convertDateForExcel(time_finish(i,1));
% 

                        % edited by EP for streamline date format 
                        file_start_f = now();
                        time_start_f = time_start(i,2);
                        time_finish_f = time_finish(i,2);

                        seconds_per_day = 24*60*60;
                        rel_start  = (time_start(i,1) - filestart)*seconds_per_day;
                        rel_finish = (time_finish(i,2) - filestart)*seconds_per_day;


                        %Run spikefinder on the found event alone to get counts of spikes.
                        sctEventSpikes = findSpikes(pdData(temp_pnCombinedEvents(i,1):temp_pnCombinedEvents(i,2),j), fs, app.sctSetting, 0);
                        evt_p = sctEventSpikes.pnPosSpikes{1};
                        evt_n = sctEventSpikes.pnNegSpikes{1};
                        evt_psn = sctEventSpikes.pnPosSpikesNarrow{1};
                        evt_nsn = sctEventSpikes.pnNegSpikesNarrow{1};
                        evt_psw = sctEventSpikes.pnPosSpikesWide{1};
                        evt_nsw = sctEventSpikes.pnNegSpikesWide{1};
                        number_of_positive_spikes = size(evt_p,1);
                        number_of_negative_spikes = size(evt_n,1);
                        number_of_positive_wide_spikes = size(evt_psw,1);
                        number_of_negative_wide_spikes = size(evt_nsw,1);
                        number_of_positive_narrow_spikes = size(evt_psn,1);
                        number_of_negative_narrow_spikes = size(evt_nsn,1);
                        perc_narrow_spike_pos = number_of_positive_narrow_spikes/number_of_positive_spikes;
                        perc_narrow_spike_neg = number_of_negative_narrow_spikes/number_of_negative_spikes;
                        narrow_spike_density_pos = number_of_positive_narrow_spikes/duration;
                        narrow_spike_density_neg = number_of_negative_narrow_spikes/duration;
                        spike_density_pos = number_of_positive_spikes/duration;
                        spike_density_neg = number_of_negative_spikes/duration;
                        if isempty(evt_p)
                            avg_spike_width_pos=0;
                        else
                            avg_spike_width_pos=mean(evt_p(:,3));
                        end
                        
                        if isempty(evt_n)
                            avg_spike_width_neg=0;
                        else
                            avg_spike_width_neg=mean(evt_n(:,3));
                        end
                        
                        plotted_additional = app.sctSetting.EventDetection.dMinSeizureLength * dSampRate;
%                         EventPlotted=figure('units','normalized','outerposition',[0 0 1 1],'Name','Event Decision');
%                         hold on;
%                         title('Plotted and Highlighted Suggested Event','FontSize',14);
%                         try
%                             plot(1:length(pdData(temp_pnCombinedEvents(i,1)-plotted_additional:temp_pnCombinedEvents(i,2)+plotted_additional,j)),pdData(temp_pnCombinedEvents(i,1)-plotted_additional:temp_pnCombinedEvents(i,2)+plotted_additional,j));
%                             xlabel(sprintf('Start at %d seconds before suggested event start and finish %d seconds after suggested event finish (length of difference in sample numbers from event finish + additional time and event start - additional time)',app.sctSetting.EventDetection.dMinSeizureLength,app.sctSetting.EventDetection.dMinSeizureLength));
%                             ylabel('Value at sample point');
%                             vertices =[1+plotted_additional min(pdData(temp_pnCombinedEvents(i,1):temp_pnCombinedEvents(i,2),j)); 1+plotted_additional max(pdData(temp_pnCombinedEvents(i,1):temp_pnCombinedEvents(i,2),j)); length(pdData(temp_pnCombinedEvents(i,1)-plotted_additional:temp_pnCombinedEvents(i,2)+plotted_additional,j))-plotted_additional max(pdData(temp_pnCombinedEvents(i,1):temp_pnCombinedEvents(i,2),j)); length(pdData(temp_pnCombinedEvents(i,1)-plotted_additional:temp_pnCombinedEvents(i,2)+plotted_additional,j))-plotted_additional min(pdData(temp_pnCombinedEvents(i,1):temp_pnCombinedEvents(i,2),j))];
%                             faces = [1 2 3 4];
%                         catch
%                             plot(1:length(pdData(temp_pnCombinedEvents(i,1):temp_pnCombinedEvents(i,2),j)),pdData(temp_pnCombinedEvents(i,1):temp_pnCombinedEvents(i,2),j));
%                             xlabel(sprintf('Start at suggested event start and finish at suggested event finish (length of difference in sample numbers start to finish)'));
%                             ylabel('Value at sample point');
%                             vertices =[1 min(pdData(temp_pnCombinedEvents(i,1):temp_pnCombinedEvents(i,2))); 1 max(pdData(temp_pnCombinedEvents(i,1):temp_pnCombinedEvents(i,2))); length(pdData(temp_pnCombinedEvents(i,1):temp_pnCombinedEvents(i,2),j)) max(pdData(temp_pnCombinedEvents(i,1):temp_pnCombinedEvents(i,2))); length(pdData(temp_pnCombinedEvents(i,1):temp_pnCombinedEvents(i,2),j)) min(pdData(temp_pnCombinedEvents(i,1):temp_pnCombinedEvents(i,2)))];
%                             faces = [1 2 3 4];
%                         end
%                         patch('Faces', faces, 'Vertices', vertices, 'FaceColor', 'yellow','FaceAlpha', 0.1);
%                         hold off;
                        
%                         newstat=[y_start, m_start, d_start, h_start, mn_start, s_start, y_finish, m_finish, d_finish, h_finish, mn_finish, s_finish, duration,...
%                             number_of_positive_spikes, number_of_negative_spikes,number_of_positive_wide_spikes,number_of_negative_wide_spikes,...
%                             number_of_positive_narrow_spikes,number_of_negative_narrow_spikes,avg_spike_width_pos,avg_spike_width_neg,...
%                             perc_narrow_spike_pos,perc_narrow_spike_neg,narrow_spike_density_pos,narrow_spike_density_neg,spike_density_pos,spike_density_neg,...
%                             fs,now(),[temp_pnCombinedEvents(i,1),temp_pnCombinedEvents(i,2)],[temp_pnCombinedEvents(i,1)/fs,temp_pnCombinedEvents(i,2)/fs]];
%                         
                        % modified by EP on 7/21/2023 to streamline date
                        % reporting
                        newstat=[file_start_f, time_start_f, time_finish_f, rel_start, rel_finish, duration,...
                            number_of_positive_spikes, number_of_negative_spikes,number_of_positive_wide_spikes,number_of_negative_wide_spikes,...
                            number_of_positive_narrow_spikes,number_of_negative_narrow_spikes,avg_spike_width_pos,avg_spike_width_neg,...
                            perc_narrow_spike_pos,perc_narrow_spike_neg,narrow_spike_density_pos,narrow_spike_density_neg,spike_density_pos,spike_density_neg,...
                            fs, now(), [temp_pnCombinedEvents(i,1),temp_pnCombinedEvents(i,2)],[temp_pnCombinedEvents(i,1)/fs,temp_pnCombinedEvents(i,2)/fs]];
                        
                        evt_stats = [evt_stats; newstat];
                        
                        category = {};
%                        while isempty(category) ||  isempty(str2num(cell2mat(category))) || (str2num(cell2mat(category)) < 1) || (str2num(cell2mat(category)) > 4)
%                            category = inputdlg(sprintf('How do you wish to designate this data?\n(1 True Event, 2 No Event, 3 Event w/ Noise, 4 Short Event)'),'Event Designation',[1 70]);
%                        end
%                         category = str2num(cell2mat(category));
                          category = 1; 
                        app.sctInfo(j).EventCategories(i) = category;
%                         close(EventPlotted)
                    end % for i=1:nr_of_events
                    final_evt_stats{j} = evt_stats;
                    if ~isempty(evt_stats)
                    
                        TimeInEvent = sum(evt_stats(:,13));
                    else
                        TimeInEvent = 0;
                    end
                    PercInEvent = TimeInEvent/filelength;
                    app.sctInfo(j).TimeInEvent = TimeInEvent;
                    app.sctInfo(j).PercInEvent = PercInEvent;
                    app.sctInfo(j).EventStats = evt_stats;

                    app.pdResult{j} = evt_stats;
                    
                    %Determine the number of spikes NOT in noise
                    if isempty(pnNoise{j}) %ALL SPIKES COUNT
                        totalposspikes = size(psn{j},1);
                        totalnegspikes = size(nsn{j},1);
                        
                        totalnoise = 0;
                    else %ONLY SPIKES NOT IN NOISE COUNT
                        inoise = 1; totalnoise = 0;
                        iposspikes=1;totalposspikes=0;
                        inegspikes=1;totalnegspikes=0;
                        temp_noise = pnNoise{j};
                        temp_pos = psn{j};
                        temp_neg = nsn{j};
                        while inoise<=length(temp_noise(:,1))    %Go through noise events
                            
                            %Count the number of positive spikes prior to this noise
                            while ~isempty(temp_pos) && iposspikes<=size(temp_pos,1) && temp_pos(iposspikes,1)<temp_noise(inoise,1)
                                totalposspikes=totalposspikes+1;
                                iposspikes=iposspikes+1;
                            end
                            %Count the number of negative spikes prior to this noise
                            while ~isempty(temp_neg) && inegspikes<=size(temp_neg,1) && temp_neg(inegspikes,1)<temp_noise(inoise,1)
                                totalnegspikes=totalnegspikes+1;
                                inegspikes=inegspikes+1;
                            end
                            
                            %There are now no more spikes left prior to noise. See if
                            %there are any IN the noise
                            while ~isempty(temp_pos) && iposspikes<=size(temp_pos,1) && temp_pos(iposspikes,1)<temp_noise(inoise,2)
                                iposspikes=iposspikes+1;
                            end
                            while ~isempty(temp_neg) && inegspikes<=size(temp_neg,1) && temp_neg(inegspikes,1)<temp_noise(inoise,2)
                                inegspikes=inegspikes+1;
                            end
                            
                            %There are now no more spikes left prior or during to noise
                            %Go to next noise
                            totalnoise=totalnoise+temp_noise(inoise,2)-temp_noise(inoise,1);
                            inoise=inoise+1;
                        end
                        
                        %There might be spikes left after the last noise event
                        if ~isempty(temp_pos)
                            totalposspikes=totalposspikes+size(temp_pos,1)-iposspikes+1;
                        else
                            totalposspikes=0;
                        end
                        if ~isempty(temp_neg)
                            totalnegspikes=totalnegspikes+size(temp_neg,1)-inegspikes+1;
                        else
                            totalnegspikes=0;
                        end
                    end % if isempty(noise) %ALL SPIKES COUNT
                    temp_pos=[];
                    temp_neg=[];
                    temp_noise=[];
                    % Calculate the #spike-statistics
                    time_in_noise = totalnoise/fs;
                    time_without_noise = filelength-time_in_noise;     %time in s
                    posspikerate = totalposspikes/time_without_noise;  %#/s
                    negspikerate = totalnegspikes/time_without_noise;  %#/s
                    
                    app.sctInfo(j).TotalTime = filelength;
                    app.sctInfo(j).TimeInNoise = time_in_noise;
                    app.sctInfo(j).totalposspikes = totalposspikes;
                    app.sctInfo(j).totalnegspikes = totalnegspikes;
                    app.sctInfo(j).posspikerate = posspikerate;
                    app.sctInfo(j).negspikerate = negspikerate;
                    
                    %Update the last analyzed time
                    app.sctInfo(j).LastAnalyzed=now();
                    evt_stats = [];
                    
                    
                end
                %Delete parallel pool to conserve memory as events have been processed already
                delete(gcp('nocreate'));
            end % if app.DetectSeizuresCheckBox.Value % if DoEvents
        end % function analyzeData(app)
        
        % ----------------------------------- %
        % USER INTERFACE
        % ----------------------------------- %
        
        function uiEnableCutoffs(app)
            if app.ManualChangesButton.Enable
                app.ManualChangesButton.Enable = 'off';
            end
            app.AutomateThisButton.Enable          = 'on';
            app.DebugButton.Enable                 = 'on';
            app.UpperBaselineEditField.Enable       = 'on';
            app.LowerBaselineEditField.Enable       = 'on';
            app.UpperSpikeCutoffEditField.Enable    = 'on';
            app.LowerSpikeCutoffEditField.Enable    = 'on';
            app.UpperEditFieldLabel.Enable          = 'on';
            app.UpperEditFieldLabel_2.Enable        = 'on';
            app.LowerEditFieldLabel.Enable          = 'on';
            app.LowerEditField_2Label.Enable        = 'on';
        end % uiEnableCutoffs(app);
        
        function uiDisableCutoffs(app)
            app.ManualChangesButton.Enable          = 'off';
            app.AutomateThisButton.Enable          = 'off';
            app.DebugButton.Enable                 = 'off';
            app.UpperBaselineEditField.Enable       = 'off';
            app.LowerBaselineEditField.Enable       = 'off';
            app.UpperSpikeCutoffEditField.Enable    = 'off';
            app.LowerSpikeCutoffEditField.Enable    = 'off';
            app.UpperEditFieldLabel.Enable          = 'off';
            app.UpperEditFieldLabel_2.Enable        = 'off';
            app.LowerEditFieldLabel.Enable          = 'off';
            app.LowerEditField_2Label.Enable        = 'off';
            app.ChannelCutoffsListBox.Enable        = 'off';
            
        end % uiEnableCutoffs(app);
        
        function uiEnableAnalysis(app)
            app.DetectNoiseCheckBox.Enable          = 'on';
            app.DetectSeizuresCheckBox.Enable       = 'on';
            app.ShowThresholdsCheckBox.Enable                = 'on';
            app.ShowNoiseCheckBox.Enable       = 'on';
            app.ShowCutoffsCheckBox.Enable    = 'on';
            app.ShowSpikesCheckBox.Enable    = 'on';
            app.AnalyzeButton.Enable          = 'on';
        end % uiEnableAnalysis(app);
    
        function uiDisableAnalysis(app)
            app.DetectNoiseCheckBox.Enable          = 'off';
            app.DetectSeizuresCheckBox.Enable       = 'off';
            app.ShowThresholdsCheckBox.Enable                = 'off';
            app.ShowNoiseCheckBox.Enable       = 'off';
            app.ShowCutoffsCheckBox.Enable    = 'off';
            app.ShowSpikesCheckBox.Enable    = 'off';
            app.AnalyzeButton.Enable          = 'off';
        end % uiDisableAnalysis(app);


        function dateString = convertDateForExcel(~, dateNumber)
            % Convert the MATLAB date number to a date string in a format Excel can read
            dateString = datestr(dateNumber, 'yyyy-mm-dd HH:MM:SS');

        end
    end % methods
    
    methods (Access = public)
        
        function sctSet = defaultSettings(app)
            
            sctSet = defaultParameters();
            
        end % function sctSet = defaultSettings(app)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize list of directories and handles
            
            % initialize setting parameters
            app.sctSetting = defaultSettings(app);
        end

        % Button pushed function: AddFilesButton, 
        % BrowseDataDirButton
        function BrowseDataDirButtonPushed(app, event)
            
            [strBrowseFileName, strBrowseFilePath] = uigetfile({'*.mat', 'MAT-files (*.mat)';  ...
                '*.acq', 'AcqKnowledge Files (*.acq)'; '*.edf', 'European Data Format (*.edf)'; ...
                '*.rhd', 'Intan RHD2000 Files (*.rhd)'; '*.xdat', 'XDAT Files (*.xdat)'; '*.*', 'All Files (*.*)'}, ...
                'Select One or More Files', 'MultiSelect', 'on');
            drawnow;    figure(app.ElectrographicSeizureAnalyzerESAUIFigure);
            if ~iscell(strBrowseFileName)
                if strBrowseFileName == 0   % click cancel or X
                    cBrowseDir = 0; % NOT a cell array
                    app.DataDirectoryEditField.Value = '(Empty)';
                else    % one file selected
                    cBrowseDir{1} = [strBrowseFilePath, strBrowseFileName];
                    app.DataDirectoryEditField.Value = [strBrowseFilePath, strBrowseFileName];
                end
            else    % multiple files selected
                cBrowseDir =  cell(length(strBrowseFileName), 1);
                for nFileNum = 1 : length(strBrowseFileName)
                    strName = string(strBrowseFileName{nFileNum});
                    cBrowseDir{nFileNum} = sprintf('%s%s', strBrowseFilePath, strName);
                end
                app.DataDirectoryEditField.Value = strjoin(strBrowseFileName, ', ');
            end
            
            updateDataDirList(app, cBrowseDir, 'add');
            
            %             keyboard;
            %             app.strFilePath = strBrowseFilePath;
            
            
            %             % display on textbox / list data directories
            %             if ~iscell(strBrowseFileName)
            %
            %                 if strBrowseFileName == 0   % click cancel or X
            %                     app.DataDirectoryEditField.Value = '(Empty)';
            %                 else    % one file selected
            %                     app.DataDirectoryEditField.Value = [strBrowseFilePath, strBrowseFileName];
            %                     cDataList = cell(1);
            %                     cDataList{1} = [strBrowseFilePath, strBrowseFileName];
            %                     app.cDataDirList{1} = cDataList;
            %                 end
            %
            %             else    % multiple files selected
            %
            %                 app.DataDirectoryEditField.Value = strjoin(strBrowseFileName, ', ');
            %                 cDataList = cell(length(strBrowseFileName), 1);
            %                 for nFileNum = 1 : length(strBrowseFileName)
            %                     strName = string(strBrowseFileName{nFileNum});
            %                     cDataList{nFileNum} = sprintf('%s%s', strBrowseFilePath, strName);
            %                 end
            %                 app.cDataDirList = cDataList;
            %
            %             end
            %
            %             % organize selected data
            % %             if ~iscell(strBrowseFileName)
            % %                 if isempty(app.cDataDirList)
            % %                     app.cDataDirList = cDataList;
            % %                 else
            % %                     app.cDataDirList{end+length(cDataList)} = cDataList;
            % %                 end
            % %             end
            %
            %             % data file list box
            %             if isempty(app.cDataDirList)
            %                 app.DataFilesListBox.Items = {'(No data selected)'};
            %             else
            %                 cDataNameList = cell(length(app.cDataDirList), 1);
            %                 cDataDisplayList = cell(length(app.cDataDirList), 1);
            %                 for nFile = 1 : length(app.cDataDirList)
            %                     strDir = string(app.cDataDirList{nFile});
            %                     [~, strName, strExt] = fileparts(strDir);
            %                     strFormat = erase(strExt, '.');
            %                     strNameExt = strcat(strName, strExt);
            %                     cDataNameList{nFile} = char(strDir);
            %                     cDataDisplayList{nFile} = char(strcat(sprintf('[%s]-', strFormat), strNameExt));
            %                 end
            %                 app.DataFilesListBox.ItemsData = cDataNameList;
            %                 app.DataFilesListBox.Items = cDataDisplayList;
            %             end
            %     %                 app.DataFilesListBox.Items = app.cDataDirList;
            
        end

        % Button pushed function: BrowseSaveDirButton
        function BrowseSaveDirButtonPushed(app, event)
            strSave = uigetdir;
            drawnow;    figure(app.ElectrographicSeizureAnalyzerESAUIFigure);
            
            if ~ischar(strSave)
                app.SaveDirectoryEditField.Value = '(Empty)';
                %                 app.strSaveDir = -1;
            else
                app.SaveDirectoryEditField.Value = strSave;
                app.strSaveDir = strSave;
            end
        end

        % Value changed function: DataFilesListBox
        function DataFilesListBoxValueChanged(app, event)
            %%%%%%%%%%%%%%%%%%%%
            % need to clear previous
            % need to update channel
            %%%%%%%%%%%%%%%%%%%
            clearPreviousFile(app);
            app.ChannelListBox.Enable = 'off';
            app.ChannelListBox.Value = {};
            app.ChannelListBox.Items = {};
            app.LoadButton.Enable    = 'off';
            
            clearPlots(app,4);
            clearThresholdData(app);
            uiDisableCutoffs(app);
            uiDisableAnalysis(app);
            
            nNumFileSelected = length(app.DataFilesListBox.Value);
            
            if nNumFileSelected == 1    % one file selected
                [~, ~, strExt] = fileparts(string(app.DataFilesListBox.Value));
                
                if (strcmp(strExt, '.mat'))
                    app.LoadButton.Enable           = 'on';
                    app.ConvertFilesButton.Enable   = 'off';
                    app.ChannelListBox.Enable       = 'on';
                    app.ChannelListBoxLabel.Enable  = 'on';
                    
                    app.objMat = matfile(string(app.DataFilesListBox.Value));
                    app.sctHeader = app.objMat.sctHeader;
                    
                    cChannels = cell(app.sctHeader.nChannelNum, 1);
                    for nChan = 1 : app.sctHeader.nChannelNum
                        cChannels{nChan} = char(num2str(nChan));
                    end
                    
                    app.ChannelListBox.Items = cChannels;
                    clear nChan cChannels;
                    
                elseif (strcmp(strExt, '.edf')||strcmp(strExt, '.acq')||strcmp(strExt, '.rhd')) || strcmp(strExt, '.xdat')
                    app.LoadButton.Enable           = 'off';
                    app.ConvertFilesButton.Enable   = 'on';
                    app.ChannelListBox.Enable       = 'off';
                    app.ChannelListBoxLabel.Enable  = 'off';
                    
                else % if (strcmp(strExt, '.mat'))
                    app.LoadButton.Enable           = 'off';
                    app.ConvertFilesButton.Enable   = 'off';
                    app.ChannelListBox.Enable       = 'off';
                    app.ChannelListBoxLabel.Enable  = 'off';
                end


            end % if (strcmp(strExt, '.mat'))
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%
            % need to clear previous analysis
            %%%%%%%%%%%%%%%%%
            if ~isempty(app.ChannelListBox.Value)
                clearPreviousFile(app);
                app.objMat = matfile(string(app.DataFilesListBox.Value));
                app.sctHeader = app.objMat.sctHeader;
                if ~isempty(app.MainEEGUIAxes)
                    clearPlots(app,4);
                end
                clearThresholdData(app);
                if strcmp(app.AnalyzeButton.Enable,'on')
                    uiDisableAnalysis(app);
                end
                
                f = waitbar(0.5, 'Loading EEG Data...', 'Name', 'Loading EEG Data');
                
                pdRawData = app.objMat.pdData;
                nChannelNum = app.sctHeader.nChannelNum;
                cChannels = cell(nChannelNum, 1);
                pdData = 0 * pdRawData;
                
                for nChan = 1 : nChannelNum
                    cChannels{nChan} = char(num2str(nChan));
                    pdData(:, nChan) = pdRawData(:, nChan) - mean(pdRawData(1:50:end, nChan));
                end
                
                app.ChannelListBox.Items = cChannels;
                app.ChannelListBox.Enable       = 'on';
                app.ChannelListBoxLabel.Enable  = 'on';
                clear nChan cChannels;
                
                app.pdEEG = pdData;
                clear pdRawData pdTempData;
                
                waitbar(1, f,'Data Loaded.');
                pause(0.5);
                close(f);
                
                nChannel = str2double(app.ChannelListBox.Value);
                [app.sctBaseGraph.nDownSampFactor, app.sctBaseGraph.pdTimeInMin, app.sctBaseGraph.pdDownSampData] = displayMainEEG(app, nChannel);
                uiEnableCutoffs(app);
            end
        end

        % Button pushed function: ConvertFilesButton
        function ConvertFilesButtonPushed(app, event)
            strFile = string(app.DataFilesListBox.Value);
            [strDir, strName, strExt] = fileparts(strFile);
            
            if strcmp(strExt, '.rhd') || strcmp(strExt,'.xdat')
                num_subjects = {};
                num_channels = {};
                while isempty(num_subjects) || isempty(str2num(cell2mat(num_subjects))) 
                    num_subjects = inputdlg('How many subjects in the file?');
                end
                while isempty(num_channels) ||  isempty(str2num(cell2mat(num_channels))) 
                    num_channels = inputdlg('How many amplifier channels per subject in the file?');
                end
                num_subjects = str2num(cell2mat(num_subjects));
                num_channels = str2num(cell2mat(num_channels));
            else
                num_subjects = 1;
            end
                      
            for subject=1:num_subjects
                if isempty(app.strSaveDir)
                    if contains(strName, sprintf('_subject_%d',subject))
                        strSave = sprintf('%s.mat', fullfile(strDir, strName));
                    else
                        strSave = sprintf('%s.mat', sprintf('%s_subject_%d',fullfile(strDir, strName), subject));
                    end
                else
                    if contains(strName, sprintf('_subject_%d',subject))
                        strSave = sprintf('%s.mat', fullfile(strDir, strName));
                    else
                        strSave = sprintf('%s.mat', sprintf('%s_subject_%d',fullfile(strDir, strName), subject));
                    end
                end
                
                if (strcmp(strExt, '.acq'))
                    %% acq converter
                    
                elseif (strcmp(strExt, '.edf'))
                    %% edf converter
                    [app.sctHeader, app.pdEEG] = edfConverter(strFile, strSave);
                    
                    app.cFileDirList{end+1} = strSave;
                    strNameExt = sprintf('%s.mat', strName);
                    if contains(strName, sprintf('_subject_%d',subject))
                       strDisplayName  = strcat('[mat]-', sprintf('%s.mat', strName));
                    else
                        strDisplayName = strcat('[mat]-', sprintf('%s.mat', strcat(strName, sprintf('_subject_%d',subject))));
                    end 
                    app.DataFilesListBox.Items{end+1} = strDisplayName;
                    app.DataFilesListBox.ItemsData{end+1} = strSave;
                    
                elseif (strcmp(strExt, '.rhd'))
                    %% rhd converter
                    [app.sctHeader, app.pdEEG, app.pdTTL] = rhdConverter(strFile, strSave, 1,subject,num_subjects,num_channels);
                    
                    app.cFileDirList{end+1} = strSave;
                    strNameExt = sprintf('%s.mat', strName);
                    
                    if contains(strName, sprintf('_subject_%d',subject))
                       strDisplayName  = strcat('[mat]-', sprintf('%s.mat', strName));
                    else
                        strDisplayName = strcat('[mat]-', sprintf('%s.mat', strcat(strName, sprintf('_subject_%d',subject))));
                    end 
                    app.DataFilesListBox.Items{end+1} = strDisplayName;
                    app.DataFilesListBox.ItemsData{end+1} = strSave;
                   
                elseif (strcmp(strExt, '.xdat'))
                    if contains(strName, sprintf('_subject_%d',subject))
                       strDisplayName  = strcat('[mat]-', sprintf('%s.mat', strName));
                    else
                        strDisplayName = strcat('[mat]-', sprintf('%s.mat', strcat(strName, sprintf('_subject_%d',subject))));
                    end 
                    [app.sctHeader, app.pdEEG] = xdatImport(strFile,strSave,subject, num_subjects,num_channels);
                    app.cFileDirList{end+1} = strSave;
                    app.DataFilesListBox.Items{end+1} = strDisplayName;
                    app.DataFilesListBox.ItemsData{end+1} = strSave;
                    
                else
                    %% no converter available
                    errordlg(sprintf('Cannot convert file in %s format.', strExt{subject}),'File Error');
                end
                
            end
        end

        % Value changed function: ChannelListBox
        function ChannelListBoxValueChanged(app, event)
            clearPreviousChannel(app);
            
            clearPlots(app,4);
            clearThresholdData(app);
            if strcmp(app.AutomateThisButton.Enable,'on')
                uiDisableCutoffs(app);
                if strcmp(app.AnalyzeButton.Enable,'on')
                    uiDisableAnalysis(app);
                end
            end
            if ~isempty(app.pdEEG) && ~isempty(app.ChannelListBox.Value)
                nChannel = str2double(app.ChannelListBox.Value);
                [app.sctBaseGraph.nDownSampFactor, app.sctBaseGraph.pdTimeInMin, app.sctBaseGraph.pdDownSampData] = displayMainEEG(app, nChannel);
                app.LoadButton.Enable = 'on';
            elseif ~isempty(app.ChannelListBox.Value)
                plotReset(app);
                app.LoadButton.Enable = 1;
            else
                plotReset(app);
                app.LoadButton.Enable = 0;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%
            % need to load data
            %%%%%%%%%%%%%%%%%%%%%%%
            
        end

        % Value changed function: MainEEGVoltMaxEditField
        function MainEEGVoltMaxEditFieldValueChanged(app, event)
            dVoltMax = app.MainEEGVoltMaxEditField.Value;
            ylim(app.MainEEGUIAxes, [app.MainEEGVoltMinEditField.Value, dVoltMax]);
        end

        % Value changed function: MainEEGVoltMinEditField
        function MainEEGVoltMinEditFieldValueChanged(app, event)
            dVoltMin = app.MainEEGVoltMinEditField.Value;
            ylim(app.MainEEGUIAxes, [dVoltMin, app.MainEEGVoltMaxEditField.Value]);
        end

        % Value changed function: MainEEGTimeMinEditField
        function MainEEGTimeMinEditFieldValueChanged(app, event)
            dTimeMin = app.MainEEGTimeMinEditField.Value;
            try     % Added 2/9/2020 by E. Pedapati to add scroll bar function
                xlim(app.MainEEGUIAxes, [dTimeMin, app.MainEEGTimeMaxEditField.Value]);
            catch
                app.ResetAxisButtonPushed;
            end
        end

        % Value changed function: MainEEGTimeMaxEditField
        function MainEEGTimeMaxEditFieldValueChanged(app, event)
            dTimeMax = app.MainEEGTimeMaxEditField.Value;
            try % Added 2/9/2020 by E. Pedapati to add scroll bar function
                xlim(app.MainEEGUIAxes, [app.MainEEGTimeMinEditField.Value, dTimeMax]);
            catch
                app.ResetAxisButtonPushed;
            end
            
        end

        % Button pushed function: OpenSettingsButton
        function OpenSettingsButtonPushed(app, event)
            app.appDialogSettingWindow = EEGSettingsWin(app, app.sctSetting);
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            
            keyboard;
        end

        % Button pushed function: AutomateThisButton
        function AutomateThisButtonPushed(app, event)
           
            app.bAfterAutoCutoff = 0;
            
            nChannel = str2double(app.ChannelListBox.Value);
            pdChanData = app.pdEEG(:, nChannel);
            if length(app.sctHeader.dSamplingRate) > 1
                dSampRate = app.sctHeader.dSamplingRate(nChannel);
            else
                dSampRate = app.sctHeader.dSamplingRate(1);
            end
            sctThresholds = calculateThresholds(pdChanData, dSampRate, app.sctSetting, app.ShowThresholdsCheckBox.Value);
            update_ThresholdsData = waitbar(0, 'Updating Window with Threshold Information', 'Name', 'Threshold Window Update');
            app.sctSetting.dBaselineUp      = sctThresholds.CutoffAllPeaks(:,2);
            app.sctSetting.dBaselineLow     = sctThresholds.CutoffAllPeaks(:,1);
            app.sctSetting.dSpikeUp         = sctThresholds.StdDvPos;
            app.sctSetting.dSpikeLow        = sctThresholds.StdDvNeg;
            app.sctSetting.strCutoffMethod  = 'Automatic';
            app.sctSetting.dNoiseUp         = sctThresholds.CutoffPosPeaks;
            app.sctSetting.dNoiseLow        = sctThresholds.CutoffNegPeaks;
            waitbar(.33,update_ThresholdsData);

            for n=1:length(nChannel)
              app.sctInfo(n).Autocutoffs.dBaselineUp      = sctThresholds.CutoffAllPeaks(n,2);
              app.sctInfo(n).Autocutoffs.dBaselineLow     = sctThresholds.CutoffAllPeaks(n,1);
              app.sctInfo(n).Autocutoffs.dSpikeUp         = sctThresholds.StdDvPos(n);
              app.sctInfo(n).Autocutoffs.dSpikeLow        = sctThresholds.StdDvNeg(n);
              app.sctInfo(n).Autocutoffs.strCutoffMethod  = 'Automatic';
            end
            
            app.bAfterAutoCutoff = 1;
            waitbar(.67,update_ThresholdsData);

            app.UpperBaselineEditField.Value = sctThresholds.CutoffAllPeaks(1,2);
            app.LowerBaselineEditField.Value = sctThresholds.CutoffAllPeaks(1,1);
            app.UpperSpikeCutoffEditField.Value = sctThresholds.StdDvPos(1);
            app.LowerSpikeCutoffEditField.Value = sctThresholds.StdDvNeg(1);
            
            app.ChannelCutoffsListBox.Items = app.ChannelListBox.Value;
            app.ChannelCutoffsListBox.Enable = 1;
            
            app.CutoffMethodLabel.Enable = 'on';
            app.CutoffMethodLabel.Text = sprintf('Cutoff Method: %s', app.sctSetting.strCutoffMethod);
            waitbar(1,update_ThresholdsData, 'Threshold Data Updated');
            delete(update_ThresholdsData);
            
            refresh_GUIThresholdData = waitbar(0, 'Updating GUI with Threshold Information', 'Name', 'Main GUI Update');
            refreshMainEEG(app);
            waitbar(1, refresh_GUIThresholdData,'GUI Updated');
            delete(refresh_GUIThresholdData);
            app.ManualChangesButton.Enable = 'on';
            uiEnableAnalysis(app);
        end

        % Button pushed function: DeleteFilesButton
        function DeleteFilesButtonPushed(app, event)
            cDeleteList = app.DataFilesListBox.Value;
            updateDataDirList(app, cDeleteList, 'delete');
            
        end

        % Button pushed function: ManualChangesButton
        function ManualChangesButtonPushed(app, event)
            if app.bAfterAutoCutoff
            
                update_ThresholdsData = waitbar(0, 'Updating Window with Threshold Information', 'Name', 'Threshold Window Update');
                cutoffChannel = app.ChannelCutoffsListBox.Value;
                if ~isempty(cutoffChannel)
                    cutoffUpdate(app,cutoffChannel);
                    waitbar(.33,update_ThresholdsData);
                else
                    cutoffUpdate(app,app.ChannelCutoffsListBox.Items{1});
                    waitbar(.33,update_ThresholdsData);
                end
                
                waitbar(.67,update_ThresholdsData);
                app.CutoffMethodLabel.Enable = 'on';
                app.CutoffMethodLabel.Text = sprintf('Cutoff Method: %s', app.sctSetting.strCutoffMethod);
                waitbar(1,update_ThresholdsData, 'Threshold Data Updated');
                delete(update_ThresholdsData);
                app.ChannelCutoffsListBox.Items = app.ChannelListBox.Value;
                app.ChannelCutoffsListBox.Enable = 1;
                refresh_GUIThresholdData = waitbar(0, 'Updating GUI with Threshold Information', 'Name', 'Main GUI Update');
                refreshMainEEG(app);
                waitbar(1, refresh_GUIThresholdData,'GUI Updated');
                delete(refresh_GUIThresholdData);
            end
        end

        % Value changed function: ShowCutoffsCheckBox
        function ShowCutoffsCheckBoxValueChanged(app, event)
            refreshMainEEG(app);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if app.bAfterAutoCutoff
                analyzeData(app);
                saveResults(app);
                displayAnalysis(app);
            end 
            
            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            % Added 2/9/2020 by E. Pedapati to add scroll bar function
            originalLimits = app.MainEEGTimeOriginalLimits;
            currentStep = app.MainEEGUIAxes.XLim(2) - app.MainEEGUIAxes.XLim(1);
            if app.MainEEGUIAxes.XLim(2) + currentStep <= originalLimits(2)
                %app.ForwardButton.Enable = 1;
                if app.MainEEGUIAxes.XLim(1) - currentStep >= originalLimits(1)
                    %  app.BackButton.Enable = 1;
                end
                app.MainEEGUIAxes.XLim = [app.MainEEGUIAxes.XLim(1) + currentStep app.MainEEGUIAxes.XLim(2) + currentStep];
                app.MainEEGTimeMinEditField.Value = app.MainEEGUIAxes.XLim(1);
                app.MainEEGTimeMaxEditField.Value = app.MainEEGUIAxes.XLim(2);
            else
                % app.ForwardButton.Enable = 0;
                app.MainEEGUIAxes.XLim = [originalLimits(2)- currentStep originalLimits(2)];
                app.MainEEGTimeMinEditField.Value = app.MainEEGUIAxes.XLim(1);
                app.MainEEGTimeMaxEditField.Value = app.MainEEGUIAxes.XLim(2);
            end
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Added 2/9/2020 by E. Pedapati to add scroll bar function
            originalLimits = app.MainEEGTimeOriginalLimits;
            currentStep = app.MainEEGUIAxes.XLim(2) - app.MainEEGUIAxes.XLim(1);
            if app.MainEEGUIAxes.XLim(1) - currentStep >= originalLimits(1)
                if app.MainEEGUIAxes.XLim(2) + currentStep <= originalLimits(2)
                    % app.ForwardButton.Enable = 1;
                end
                % app.BackButton.Enable = 1;
                app.MainEEGUIAxes.XLim = [app.MainEEGUIAxes.XLim(1) - currentStep app.MainEEGUIAxes.XLim(2) - currentStep];
                app.MainEEGTimeMinEditField.Value = app.MainEEGUIAxes.XLim(1);
                app.MainEEGTimeMaxEditField.Value = app.MainEEGUIAxes.XLim(2);
            else
                % app.BackButton.Enable = 0;
                app.MainEEGUIAxes.XLim = [originalLimits(1) originalLimits(1)+ currentStep];
                app.MainEEGTimeMinEditField.Value = app.MainEEGUIAxes.XLim(1);
                app.MainEEGTimeMaxEditField.Value = app.MainEEGUIAxes.XLim(2);
            end
        end

        % Button pushed function: ResetAxisButton
        function ResetAxisButtonPushed(app, event)
            % Added 2/9/2020 by E. Pedapati to add scroll bar function
            if ~isempty(app.MainEEGUIAxes.XLim)
                app.MainEEGUIAxes.XLim = app.MainEEGTimeOriginalLimits;
                app.MainEEGTimeMinEditField.Value = app.MainEEGUIAxes.XLim(1);
                app.MainEEGTimeMaxEditField.Value = app.MainEEGUIAxes.XLim(2);
                
                app.MainEEGSecondUIAxes.XLim = app.MainEEGTimeOriginalLimits;
                app.MainEEGTimeMinEditField.Value = app.MainEEGSecondUIAxes.XLim(1);
                app.MainEEGTimeMaxEditField.Value = app.MainEEGSecondUIAxes.XLim(2);
                
                app.MainEEGThirdUIAxes.XLim = app.MainEEGTimeOriginalLimits;
                app.MainEEGTimeMinEditField.Value = app.MainEEGThirdUIAxes.XLim(1);
                app.MainEEGTimeMaxEditField.Value = app.MainEEGThirdUIAxes.XLim(2);
                
                app.MainEEGFourthUIAxes.XLim = app.MainEEGTimeOriginalLimits;
                app.MainEEGTimeMinEditField.Value = app.MainEEGFourthUIAxes.XLim(1);
                app.MainEEGTimeMaxEditField.Value = app.MainEEGFourthUIAxes.XLim(2);
                
                app.ForwardButton.Enable = 1;
                app.BackButton.Enable = 1;
            end
            
        end

        % Value changed function: DetectNoiseCheckBox
        function DetectNoiseCheckBoxValueChanged(app, event)
            value = app.DetectNoiseCheckBox.Value;
            
        end

        % Value changed function: ChannelCutoffsListBox
        function ChannelCutoffsListBoxValueChanged(app, event)

            value = app.ChannelCutoffsListBox.Value;
            if ~isempty(value)
                [~,value_index] = ismember(value, app.ChannelCutoffsListBox.Items);
                app.UpperBaselineEditField.Value = app.sctSetting.dBaselineUp(value_index);
                app.LowerBaselineEditField.Value = app.sctSetting.dBaselineLow(value_index);
                app.UpperSpikeCutoffEditField.Value = app.sctSetting.dSpikeUp(value_index);
                app.LowerSpikeCutoffEditField.Value = app.sctSetting.dSpikeLow(value_index);
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElectrographicSeizureAnalyzerESAUIFigure = uifigure('Visible', 'off');
            app.ElectrographicSeizureAnalyzerESAUIFigure.Name = 'Electrographic Seizure Analyzer (ESA)';
            app.ChooseFilesPanel = uipanel(app.ElectrographicSeizureAnalyzerESAUIFigure);
            app.ChooseFilesPanel.Title = 'Choose Files';
            app.DataDirectoryEditFieldLabel = uilabel(app.ChooseFilesPanel);
            app.DataDirectoryEditFieldLabel.Text = 'Data Directory';
            app.DataDirectoryEditField = uieditfield(app.ChooseFilesPanel, 'text');
            app.BrowseDataDirButton = uibutton(app.ChooseFilesPanel, 'push');
            app.BrowseDataDirButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataDirButtonPushed, true);
            app.BrowseDataDirButton.Text = 'Browse';
            app.SaveDirectoryEditFieldLabel = uilabel(app.ChooseFilesPanel);
            app.SaveDirectoryEditFieldLabel.Text = 'Save Directory';
            app.SaveDirectoryEditField = uieditfield(app.ChooseFilesPanel, 'text');
            app.BrowseSaveDirButton = uibutton(app.ChooseFilesPanel, 'push');
            app.BrowseSaveDirButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSaveDirButtonPushed, true);
            app.BrowseSaveDirButton.Text = 'Browse';
            app.DataFilesListBoxLabel = uilabel(app.ChooseFilesPanel);
            app.DataFilesListBoxLabel.Text = 'Data Files';
            app.DataFilesListBox = uilistbox(app.ChooseFilesPanel);
            app.DataFilesListBox.Items = {};
            app.DataFilesListBox.Multiselect = 'on';
            app.DataFilesListBox.ValueChangedFcn = createCallbackFcn(app, @DataFilesListBoxValueChanged, true);
            app.DataFilesListBox.Value = {};
            app.ChannelListBoxLabel = uilabel(app.ChooseFilesPanel);
            app.ChannelListBoxLabel.Enable = 'off';
            app.ChannelListBoxLabel.Text = 'Channel';
            app.ChannelListBox = uilistbox(app.ChooseFilesPanel);
            app.ChannelListBox.Items = {'1', '2', '3', '4', '5'};
            app.ChannelListBox.Multiselect = 'on';
            app.ChannelListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelListBoxValueChanged, true);
            app.ChannelListBox.Enable = 'off';
            app.ChannelListBox.Value = {'1'};
            app.LoadButton = uibutton(app.ChooseFilesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.ConvertFilesButton = uibutton(app.ChooseFilesPanel, 'push');
            app.ConvertFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertFilesButtonPushed, true);
            app.ConvertFilesButton.Enable = 'off';
            app.ConvertFilesButton.Text = 'Convert Files';
            app.AddFilesButton = uibutton(app.ChooseFilesPanel, 'push');
            app.AddFilesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataDirButtonPushed, true);
            app.AddFilesButton.Text = 'Add';
            app.DeleteFilesButton = uibutton(app.ChooseFilesPanel, 'push');
            app.DeleteFilesButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFilesButtonPushed, true);
            app.DeleteFilesButton.Text = 'Delete';
            app.CutoffsPanel = uipanel(app.ElectrographicSeizureAnalyzerESAUIFigure);
            app.CutoffsPanel.Title = 'Cutoffs';
            app.BaselinePanel = uipanel(app.CutoffsPanel);
            app.BaselinePanel.Title = 'Baseline';
            app.UpperEditFieldLabel = uilabel(app.BaselinePanel);
            app.UpperEditFieldLabel.Enable = 'off';
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperBaselineEditField = uieditfield(app.BaselinePanel, 'numeric');
            app.UpperBaselineEditField.Enable = 'off';
            app.LowerEditFieldLabel = uilabel(app.BaselinePanel);
            app.LowerEditFieldLabel.Enable = 'off';
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerBaselineEditField = uieditfield(app.BaselinePanel, 'numeric');
            app.LowerBaselineEditField.Enable = 'off';
            app.SpikeCutoffsPanel = uipanel(app.CutoffsPanel);
            app.SpikeCutoffsPanel.Title = 'Spike Cutoffs';
            app.UpperEditFieldLabel_2 = uilabel(app.SpikeCutoffsPanel);
            app.UpperEditFieldLabel_2.Enable = 'off';
            app.UpperEditFieldLabel_2.Text = 'Upper';
            app.UpperSpikeCutoffEditField = uieditfield(app.SpikeCutoffsPanel, 'numeric');
            app.UpperSpikeCutoffEditField.Enable = 'off';
            app.LowerEditField_2Label = uilabel(app.SpikeCutoffsPanel);
            app.LowerEditField_2Label.Enable = 'off';
            app.LowerEditField_2Label.Text = 'Lower';
            app.LowerSpikeCutoffEditField = uieditfield(app.SpikeCutoffsPanel, 'numeric');
            app.LowerSpikeCutoffEditField.Enable = 'off';
            app.ManualChangesButton = uibutton(app.CutoffsPanel, 'push');
            app.ManualChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ManualChangesButtonPushed, true);
            app.ManualChangesButton.Enable = 'off';
            app.ManualChangesButton.Text = 'Manual Changes';
            app.AutomateThisButton = uibutton(app.CutoffsPanel, 'push');
            app.AutomateThisButton.ButtonPushedFcn = createCallbackFcn(app, @AutomateThisButtonPushed, true);
            app.AutomateThisButton.Enable = 'off';
            app.AutomateThisButton.Text = 'Automate This';
            app.DebugButton = uibutton(app.CutoffsPanel, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Enable = 'off';
            app.DebugButton.Text = 'Debug';
            app.CutoffMethodLabel = uilabel(app.CutoffsPanel);
            app.CutoffMethodLabel.Enable = 'off';
            app.CutoffMethodLabel.Text = 'Cutoff method:';
            app.ChannelCutoffsListBoxLabel = uilabel(app.CutoffsPanel);
            app.ChannelCutoffsListBoxLabel.Text = 'Channel Cutoffs';
            app.ChannelCutoffsListBox = uilistbox(app.CutoffsPanel);
            app.ChannelCutoffsListBox.Items = {'1', '2', '3', '4'};
            app.ChannelCutoffsListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelCutoffsListBoxValueChanged, true);
            app.ChannelCutoffsListBox.Enable = 'off';
            app.ChannelCutoffsListBox.Value = '1';
            app.RunAnalysisPanel = uipanel(app.ElectrographicSeizureAnalyzerESAUIFigure);
            app.RunAnalysisPanel.Title = 'Run Analysis';
            app.DetectNoiseCheckBox = uicheckbox(app.RunAnalysisPanel);
            app.DetectNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetectNoiseCheckBoxValueChanged, true);
            app.DetectNoiseCheckBox.Enable = 'off';
            app.DetectNoiseCheckBox.Text = 'Detect Noise';
            app.DetectNoiseCheckBox.Value = true;
            app.DetectSeizuresCheckBox = uicheckbox(app.RunAnalysisPanel);
            app.DetectSeizuresCheckBox.Enable = 'off';
            app.DetectSeizuresCheckBox.Text = 'Detect Seizures';
            app.DetectSeizuresCheckBox.Value = true;
            app.DisplaysettingsPanel = uipanel(app.RunAnalysisPanel);
            app.DisplaysettingsPanel.Title = 'Display settings';
            app.ShowThresholdsCheckBox = uicheckbox(app.DisplaysettingsPanel);
            app.ShowThresholdsCheckBox.Enable = 'off';
            app.ShowThresholdsCheckBox.Text = 'Show Thresholds';
            app.ShowThresholdsCheckBox.Value = true;
            app.ShowNoiseCheckBox = uicheckbox(app.DisplaysettingsPanel);
            app.ShowNoiseCheckBox.Enable = 'off';
            app.ShowNoiseCheckBox.Text = 'Show Noise';
            app.ShowNoiseCheckBox.Value = true;
            app.ShowCutoffsCheckBox = uicheckbox(app.DisplaysettingsPanel);
            app.ShowCutoffsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCutoffsCheckBoxValueChanged, true);
            app.ShowCutoffsCheckBox.Enable = 'off';
            app.ShowCutoffsCheckBox.Text = 'Show Cutoffs';
            app.ShowCutoffsCheckBox.Value = true;
            app.ShowSpikesCheckBox = uicheckbox(app.DisplaysettingsPanel);
            app.ShowSpikesCheckBox.Enable = 'off';
            app.ShowSpikesCheckBox.Text = 'Show Spikes';
            app.ShowSpikesCheckBox.Value = true;
            app.AnalyzeButton = uibutton(app.RunAnalysisPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Enable = 'off';
            app.AnalyzeButton.Text = 'Analyze';
            app.SettingsPanel = uipanel(app.ElectrographicSeizureAnalyzerESAUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.BufferMonitorTextAreaLabel = uilabel(app.SettingsPanel);
            app.BufferMonitorTextAreaLabel.Text = 'Buffer Monitor';
            app.BufferMonitorTextArea = uitextarea(app.SettingsPanel);
            app.OpenSettingsButton = uibutton(app.SettingsPanel, 'push');
            app.OpenSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSettingsButtonPushed, true);
            app.OpenSettingsButton.Text = 'Open Settings';
            app.ForwardButton = uibutton(app.ElectrographicSeizureAnalyzerESAUIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Enable = 'off';
            app.ForwardButton.Text = 'Forward';
            app.BackButton = uibutton(app.ElectrographicSeizureAnalyzerESAUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Enable = 'off';
            app.BackButton.Text = 'Back';
            app.MainEEGUIPanel = uipanel(app.ElectrographicSeizureAnalyzerESAUIFigure);
            app.MainEEGUIPanel.Title = 'EEG Plots';
            app.MainEEGVoltMinEditField = uieditfield(app.MainEEGUIPanel, 'numeric');
            app.MainEEGVoltMinEditField.ValueChangedFcn = createCallbackFcn(app, @MainEEGVoltMinEditFieldValueChanged, true);
            app.MainEEGVoltMinEditField.Value = -500;
            app.MainEEGVoltMaxEditField = uieditfield(app.MainEEGUIPanel, 'numeric');
            app.MainEEGVoltMaxEditField.ValueChangedFcn = createCallbackFcn(app, @MainEEGVoltMaxEditFieldValueChanged, true);
            app.MainEEGVoltMaxEditField.Value = 500;
            app.MainEEGTimeMaxEditField = uieditfield(app.MainEEGUIPanel, 'numeric');
            app.MainEEGTimeMaxEditField.Limits = [0 Inf];
            app.MainEEGTimeMaxEditField.ValueChangedFcn = createCallbackFcn(app, @MainEEGTimeMaxEditFieldValueChanged, true);
            app.MainEEGTimeMinEditField = uieditfield(app.MainEEGUIPanel, 'numeric');
            app.MainEEGTimeMinEditField.Limits = [0 Inf];
            app.MainEEGTimeMinEditField.ValueChangedFcn = createCallbackFcn(app, @MainEEGTimeMinEditFieldValueChanged, true);
            app.ResetAxisButton = uibutton(app.MainEEGUIPanel, 'push');
            app.ResetAxisButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAxisButtonPushed, true);
            app.ResetAxisButton.Enable = 'off';
            app.ResetAxisButton.Text = 'Reset Axis';
            app.MainEEGSecondUIAxes = uiaxes(app.MainEEGUIPanel);
            title(app.MainEEGSecondUIAxes, 'EEG Signal')
            xlabel(app.MainEEGSecondUIAxes, 'Time, min')
            ylabel(app.MainEEGSecondUIAxes, 'Voltage')
            app.MainEEGSecondUIAxes.YLim = [-500 500];
            app.MainEEGFourthUIAxes = uiaxes(app.MainEEGUIPanel);
            title(app.MainEEGFourthUIAxes, 'EEG Signal')
            xlabel(app.MainEEGFourthUIAxes, 'Time, min')
            ylabel(app.MainEEGFourthUIAxes, 'Voltage')
            app.MainEEGFourthUIAxes.YLim = [-500 500];
            app.MainEEGUIAxes = uiaxes(app.MainEEGUIPanel);
            title(app.MainEEGUIAxes, 'EEG Signal')
            xlabel(app.MainEEGUIAxes, 'Time, min')
            ylabel(app.MainEEGUIAxes, 'Voltage')
            app.MainEEGUIAxes.YLim = [-500 500];
            app.MainEEGThirdUIAxes = uiaxes(app.MainEEGUIPanel);
            title(app.MainEEGThirdUIAxes, 'EEG Signal')
            xlabel(app.MainEEGThirdUIAxes, 'Time, min')
            ylabel(app.MainEEGThirdUIAxes, 'Voltage')
            app.MainEEGThirdUIAxes.YLim = [-500 500];
            % Show the figure after all components are created
            app.ElectrographicSeizureAnalyzerESAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESA_Extended

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElectrographicSeizureAnalyzerESAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElectrographicSeizureAnalyzerESAUIFigure)
        end
    end
end